classdef app_SISAPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SIS_APS                     matlab.ui.Figure
        systemMenu                  matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        Menu_help                   matlab.ui.container.Menu
        Label_message               matlab.ui.control.Label
        Label_theme                 matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        mainpageTab                 matlab.ui.container.Tab
        V10Label                    matlab.ui.control.Label
        Image_flag                  matlab.ui.control.Image
        Image_navigator             matlab.ui.control.Image
        ordermanagementTab          matlab.ui.container.Tab
        Button_setSchedulingPeriod  matlab.ui.control.Button
        EditField_schedulingPeriod  matlab.ui.control.EditField
        Label_88                    matlab.ui.control.Label
        Button_setCraftNo           matlab.ui.control.Button
        DropDown_craftNo            matlab.ui.control.DropDown
        Label_87                    matlab.ui.control.Label
        edtQuantity                 matlab.ui.control.NumericEditField
        Label_70                    matlab.ui.control.Label
        dpDeliveryDate              matlab.ui.control.DatePicker
        Label_69                    matlab.ui.control.Label
        edtCustomer                 matlab.ui.control.EditField
        Label_68                    matlab.ui.control.Label
        edtProduct                  matlab.ui.control.EditField
        Label_67                    matlab.ui.control.Label
        edtOrderID                  matlab.ui.control.EditField
        Label_66                    matlab.ui.control.Label
        generateplanButton          matlab.ui.control.Button
        ONoTextArea                 matlab.ui.control.TextArea
        Label_57                    matlab.ui.control.Label
        deleteOrderButton           matlab.ui.control.Button
        modifyOrderButton           matlab.ui.control.Button
        Button_addOrder             matlab.ui.control.Button
        UITable_order               matlab.ui.control.Table
        queryButton                 matlab.ui.control.Button
        orderlistLabel              matlab.ui.control.Label
        craftmanagementTab          matlab.ui.container.Tab
        STDropDown                  matlab.ui.control.DropDown
        Label_97                    matlab.ui.control.Label
        DropDown_eqIDTmp            matlab.ui.control.DropDown
        eqIDLabel                   matlab.ui.control.Label
        equipmentlistLabel          matlab.ui.control.Label
        EditField_processOrder      matlab.ui.control.NumericEditField
        Label_79                    matlab.ui.control.Label
        EditField_eqTime            matlab.ui.control.NumericEditField
        Label_78                    matlab.ui.control.Label
        DropDown_process            matlab.ui.control.DropDown
        Label_77                    matlab.ui.control.Label
        DropDown_Eq                 matlab.ui.control.DropDown
        Label_76                    matlab.ui.control.Label
        craftdetailLabel            matlab.ui.control.Label
        Button_delCraftEq           matlab.ui.control.Button
        Button_addCraftEq           matlab.ui.control.Button
        Button_delCraftDet          matlab.ui.control.Button
        Button_addCraftDet          matlab.ui.control.Button
        UITableCraftEq              matlab.ui.control.Table
        UITableCraftDet             matlab.ui.control.Table
        EditField_craftDesc         matlab.ui.control.EditField
        Label_73                    matlab.ui.control.Label
        EditField_craftNo           matlab.ui.control.EditField
        Label_72                    matlab.ui.control.Label
        EditField_craftVersion      matlab.ui.control.EditField
        Label_71                    matlab.ui.control.Label
        PNoTextArea                 matlab.ui.control.TextArea
        Label_56                    matlab.ui.control.Label
        UITable_craft               matlab.ui.control.Table
        updateCraftButton           matlab.ui.control.Button
        deleteCraftButton           matlab.ui.control.Button
        addCraftButton              matlab.ui.control.Button
        queryButton_2               matlab.ui.control.Button
        craftlistLabel              matlab.ui.control.Label
        equipmentmanagementTab      matlab.ui.container.Tab
        EditField_equipCompany      matlab.ui.control.EditField
        Label_86                    matlab.ui.control.Label
        TextArea_equipMemo          matlab.ui.control.TextArea
        Label_85                    matlab.ui.control.Label
        DatePicker_buyTime          matlab.ui.control.DatePicker
        Label_84                    matlab.ui.control.Label
        EditField_equipNo           matlab.ui.control.EditField
        Label_82                    matlab.ui.control.Label
        EditField_equipName         matlab.ui.control.EditField
        Label_81                    matlab.ui.control.Label
        ENTextArea                  matlab.ui.control.TextArea
        Label_55                    matlab.ui.control.Label
        Button_updateEquipmentData  matlab.ui.control.Button
        Button_deleteEquipmentData  matlab.ui.control.Button
        Button_addEqData            matlab.ui.control.Button
        equipmentlistLabel_2        matlab.ui.control.Label
        UITable_equipment           matlab.ui.control.Table
        queryButton_4               matlab.ui.control.Button
        jobplanTab                  matlab.ui.container.Tab
        Label_slatMsg               matlab.ui.control.Label
        TextArea_schPeriod          matlab.ui.control.TextArea
        Label_60                    matlab.ui.control.Label
        TextArea_orderNoQry         matlab.ui.control.TextArea
        Label_58                    matlab.ui.control.Label
        editButton                  matlab.ui.control.Button
        deleteButton                matlab.ui.control.Button
        addButton                   matlab.ui.control.Button
        queryButton_3               matlab.ui.control.Button
        UITable_jobPlan             matlab.ui.control.Table
        jobschedulingTab            matlab.ui.container.Tab
        DatePicker_startTime        matlab.ui.control.DatePicker
        Label_103                   matlab.ui.control.Label
        DropDown_kpi                matlab.ui.control.DropDown
        Label_99                    matlab.ui.control.Label
        DropDown_algo               matlab.ui.control.DropDown
        Label_98                    matlab.ui.control.Label
        Button_scheduling           matlab.ui.control.Button
        Button_qryJobplan           matlab.ui.control.Button
        TabGroup2                   matlab.ui.container.TabGroup
        jobplanTab_2                matlab.ui.container.Tab
        UITable_9                   matlab.ui.control.Table
        parameterSettingsTab        matlab.ui.container.Tab
        EditField_maxRun            matlab.ui.control.NumericEditField
        Label_102                   matlab.ui.control.Label
        EditField_maxEva            matlab.ui.control.NumericEditField
        Label_101                   matlab.ui.control.Label
        EditField_popSize           matlab.ui.control.NumericEditField
        Label_100                   matlab.ui.control.Label
        TextArea_5                  matlab.ui.control.TextArea
        SchedCycLabel               matlab.ui.control.Label
        EditField_bestObj           matlab.ui.control.EditField
        Label_4                     matlab.ui.control.Label
        EditField_optimizedSof      matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
    end

    methods (Static)
        function [conn]=getConn()
            conn=database('SISAPS_EN','','');%%Connect to database
        end
    end
    properties (Access = private)
        LangManager      % Language manager
        Data             % Application data
        Timer            % Timer
        IsRunning = false % Operation status
    end
    %% author：Xiangbo Qi and Xu ma
    %% 20260131
    %% shenyang university
    methods (Access = private)
        function updateAvailableLanguages(app)
            % Update available language list
            availableLangs = app.LangManager.getAvailableLanguages();
            app.LanguageDropDown.Items = availableLangs;

            if ~isempty(availableLangs)
                app.LanguageDropDown.Enable = 'on';

                % Set the currently selected language
                currentLang = app.LangManager.CurrentLanguage;
                if ismember(currentLang, availableLangs)
                    app.LanguageDropDown.Value = currentLang;
                else
                    app.LanguageDropDown.Value = availableLangs{1};
                end
            else
                app.LanguageDropDown.Enable = 'off';
                app.LanguageDropDown.Items = {'No languages available'};
            end
        end
        function showMessage(app, messageKey, varargin)
            % Display localized messages
            title = app.LangManager.getText('dialog.confirm');
            message = app.LangManager.getText(messageKey, varargin{:});
            uialert(app.UIFigure, message, title);
        end
        function updateUIText(app)
            % Update all UI text
            try
                
                app.ordermanagementTab.Title= app.LangManager.getText('tab.orderManagement');
                app.craftmanagementTab.Title=app.LangManager.getText('tab.craftManagement');
                app.equipmentmanagementTab.Title=app.LangManager.getText('tab.equipmentManagement');
                app.jobplanTab.Title=app.LangManager.getText('tab.jobPlan');
                app.jobschedulingTab.Title=app.LangManager.getText('tab.jobScheduling');
                app.mainpageTab.Title=app.LangManager.getText('tab.mainPage');
                app.parameterSettingsTab.Title=app.LangManager.getText('tab.parameterSetting');
                app.jobplanTab_2.Title=app.LangManager.getText('tab.jobScheduling');
                imageSrc=app.LangManager.getText('image.src');
                app.Image_navigator.ImageSource=imageSrc;
                % Set menu text
                app.systemMenu.Text=app.LangManager.getText('menu.file.sys');
                app.Menu_help.Text=app.LangManager.getText('menu.file.help');
                app.ExitMenu.Text=app.LangManager.getText('menu.file.exit');
                
                % % Set label text
                app.Label_57.Text=app.LangManager.getText('label.edtOrderID');
                app.Label_58.Text=app.LangManager.getText('label.edtOrderID');
                app.Label_66.Text=app.LangManager.getText('label.edtOrderID');
                app.Label_66.Tooltip=app.LangManager.getText('tooltip.edtOrderID');
                app.Label_67.Text=app.LangManager.getText('label.edtProduct');
                app.Label_67.Tooltip=app.LangManager.getText('tooltip.edtProduct');
                app.Label_68.Text=app.LangManager.getText('label.customerName');
                app.Label_68.Tooltip=app.LangManager.getText('tooltip.customerName');
                app.Label_69.Text=app.LangManager.getText('label.deliveryDate');
                app.Label_69.Tooltip=app.LangManager.getText('tooltip.deliveryDate');
                app.Label_72.Text=app.LangManager.getText('label.processNumber');
                app.Label_72.Tooltip=app.LangManager.getText('tooltip.processNumber');
                app.Label_87.Text=app.LangManager.getText('label.processNumber');
                app.Label_87.Tooltip=app.LangManager.getText('tooltip.processNumber');
                app.Label_56.Text=app.LangManager.getText('label.processNumber');
                app.Label_56.Tooltip=app.LangManager.getText('tooltip.processNumber');

                app.SchedCycLabel.Text=app.LangManager.getText('label.schedulingCycle');
                app.SchedCycLabel.Tooltip=app.LangManager.getText('tooltip.schedulingCycle');
                app.Label_60.Text=app.LangManager.getText('label.schedulingCycle');
                app.Label_60.Tooltip=app.LangManager.getText('tooltip.schedulingCycle');
                app.Label_88.Text=app.LangManager.getText('label.schedulingCycle');
                app.Label_88.Tooltip=app.LangManager.getText('tooltip.schedulingCycle');
                app.SchedCycLabel.Text=app.LangManager.getText('label.schedulingCycle');
                app.SchedCycLabel.Tooltip=app.LangManager.getText('tooltip.schedulingCycle');
                app.Label_70.Text=app.LangManager.getText('label.quantity');
                app.Label_70.Tooltip=app.LangManager.getText('tooltip.quantity');
                app.Label_theme.Text=app.LangManager.getText('label.theme');
                app.orderlistLabel.Text=app.LangManager.getText('label.orderList');
                app.craftlistLabel.Text=app.LangManager.getText('label.craftList');
                app.craftdetailLabel.Text=app.LangManager.getText('label.craftDetList');
                app.equipmentlistLabel.Text=app.LangManager.getText('label.equipmentList');
                app.Label_4.Text=app.LangManager.getText('label.optimalObjectiveValue');
                app.Label_4.Tooltip=app.LangManager.getText('tooltip.optimalObjectiveValue');
                app.Label.Text=app.LangManager.getText('label.optimizedSequenceValue');
                app.Label.Tooltip=app.LangManager.getText('tooltip.optimizedSequenceValue');
                app.Label_100.Text=app.LangManager.getText('label.popSize');
                app.Label_100.Tooltip=app.LangManager.getText('tooltip.popSize');
                app.Label_101.Text=app.LangManager.getText('label.evaluationCount');
                app.Label_101.Tooltip=app.LangManager.getText('tooltip.evaluationCount');
                app.Label_102.Text=app.LangManager.getText('label.runTimes');
                app.Label_102.Tooltip=app.LangManager.getText('tooltip.runTimes');
                app.Label_98.Text=app.LangManager.getText('label.algorithmName');
                app.Label_99.Text=app.LangManager.getText('label.KPI');
                app.Label_73.Text=app.LangManager.getText('label.processDescription');
                app.Label_71.Text=app.LangManager.getText('label.version');
                app.Label_97.Text=app.LangManager.getText('label.workshop');
                app.Label_76.Text=app.LangManager.getText('label.equipmentName');
                app.Label_77.Text=app.LangManager.getText('label.operationName');
                app.Label_79.Text=app.LangManager.getText('label.operationSequence');
                app.Label_78.Text=app.LangManager.getText('label.operationTime');
                app.Label_81.Text=app.LangManager.getText('label.equipmentName');
                app.Label_55.Text=app.LangManager.getText('label.equipmentName');
                app.Label_86.Text=app.LangManager.getText('label.equipmentSupplier');
                app.Label_84.Text =app.LangManager.getText('label.purchaseTime');
                app.Label_82.Text=app.LangManager.getText('label.equipmentCode');
                app.Label_85.Text=app.LangManager.getText('label.memo');
                app.equipmentlistLabel_2.Text=app.LangManager.getText('label.equipmentList');
                app.Label_slatMsg.Text=app.LangManager.getText('label.msgArea');
                app.Label_message.Text=app.LangManager.getText('label.msgArea');
                app.Label_103.Text=app.LangManager.getText('label.startTime');
                
                % % Set button text
                app.Button_setCraftNo.Text =app.LangManager.getText('button.setCraftNo');
                app.Button_setCraftNo.Tooltip=app.LangManager.getText('tooltip.setCraftNo');
                app.Button_qryJobplan.Text=app.LangManager.getText('button.qryJobPlan');
                app.Button_scheduling.Text=app.LangManager.getText('button.startOpt');
                app.Button_setSchedulingPeriod.Text=app.LangManager.getText('button.setSchedulingPeriod');
                app.Button_setSchedulingPeriod.Tooltip=app.LangManager.getText('tooltip.setSchedulingPeriod');
                app.generateplanButton.Text=app.LangManager.getText('button.generateJobplan');
                app.generateplanButton.Tooltip=app.LangManager.getText('tooltip.generateJobplan');
                app.queryButton.Text=app.LangManager.getText('button.query');
                app.queryButton.Tooltip=app.LangManager.getText('tooltip.queryOrder');
                app.Button_addOrder.Text=app.LangManager.getText('button.add');
                app.Button_addOrder.Tooltip=app.LangManager.getText('tooltip.addOrder');
                app.deleteOrderButton.Text=app.LangManager.getText('button.delete');
                app.deleteOrderButton.Tooltip=app.LangManager.getText('tooltip.deleteOrder');
                app.modifyOrderButton.Text=app.LangManager.getText('button.edit');
                app.modifyOrderButton.Tooltip=app.LangManager.getText('tooltip.editOrder');

                app.queryButton_2.Text=app.LangManager.getText('button.query');
                app.queryButton_2.Tooltip=app.LangManager.getText('tooltip.queryCraft');
                app.addCraftButton.Text=app.LangManager.getText('button.add');
                app.addCraftButton.Tooltip=app.LangManager.getText('tooltip.addCraft');
                app.deleteCraftButton.Text=app.LangManager.getText('button.delete');
                app.deleteCraftButton.Tooltip=app.LangManager.getText('tooltip.deleteCraft');
                app.updateCraftButton.Text=app.LangManager.getText('button.edit');
                app.updateCraftButton.Tooltip=app.LangManager.getText('tooltip.editCraft');

                app.Button_addCraftDet.Text=app.LangManager.getText('button.add');
                app.Button_addCraftDet.Tooltip=app.LangManager.getText('tooltip.addCraftDetail');
                app.Button_delCraftDet.Text=app.LangManager.getText('button.delete');
                app.Button_delCraftDet.Tooltip=app.LangManager.getText('tooltip.deleteCraftDetail');

                app.Button_addCraftEq.Text=app.LangManager.getText('button.add');
                app.Button_addCraftEq.Tooltip=app.LangManager.getText('tooltip.addEquipment');
                app.Button_delCraftEq.Text=app.LangManager.getText('button.delete');
                app.Button_delCraftEq.Tooltip=app.LangManager.getText('tooltip.deleteEquipment');


                app.queryButton_4.Text=app.LangManager.getText('button.query');
                app.queryButton_4.Tooltip=app.LangManager.getText('tooltip.queryEquipment');
                app.Button_addEqData.Text=app.LangManager.getText('button.add');
                app.Button_addEqData.Tooltip=app.LangManager.getText('tooltip.addEquipment');
                app.Button_deleteEquipmentData.Text=app.LangManager.getText('button.delete');
                app.Button_deleteEquipmentData.Tooltip=app.LangManager.getText('tooltip.deleteEquipment');
                app.Button_updateEquipmentData.Text=app.LangManager.getText('button.edit');
                app.Button_updateEquipmentData.Tooltip=app.LangManager.getText('tooltip.editEquipment');

                app.queryButton_3.Text=app.LangManager.getText('button.query');
                app.queryButton_3.Tooltip=app.LangManager.getText('tooltip.queryJobplan');
                app.addButton.Text=app.LangManager.getText('button.add');
                app.addButton.Tooltip=app.LangManager.getText('tooltip.addJobplan');
                app.deleteButton.Text=app.LangManager.getText('button.delete');
                app.deleteButton.Tooltip=app.LangManager.getText('tooltip.deleteJobplan');
                app.editButton.Text=app.LangManager.getText('button.edit');
           
                app.editButton.Tooltip=app.LangManager.getText('tooltip.editJobplan');

                %%dropdown
                % app.DropDown_Eq.Value=app.LangManager.getText('dropdown.dataToBeLoaded');
                % app.DropDown_Eq.Items=app.LangManager.getText('dropdown.dataToBeLoaded');
                
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error updating interface text: %s', ME.message), ...
                    app.LangManager.getText('error.title'));
            end
        end
        %% update the operation time for job plan
        function updatingJobPlanOperationTime(app,startTime,endTime,orderNo,processSeq,eqName)
            startTime=string(startTime);
            endTime=string(endTime);
            orderNo=string(orderNo);
            processSeq=string(processSeq);
            tableName=app.LangManager.getText('table.jobPlan.name');
            tableName=string(tableName);
            fieldOrderNo=app.LangManager.getText('field.OperationPlan.OrderNo');
            fieldOrderNo=string(fieldOrderNo);
            fieldProcessSeq=app.LangManager.getText('field.OperationPlan.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldStartTime=app.LangManager.getText('field.OperationPlan.StartTime');
            fieldStartTime=string(fieldStartTime);
            fieldEndTime=app.LangManager.getText('field.OperationPlan.EndTime');
            fieldEndTime=string(fieldEndTime);
            fieldEquipmentName=app.LangManager.getText('field.OperationPlan.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);

            updSql="update "+tableName+" set " + fieldStartTime + "=#" + startTime + "#," + fieldEndTime + "=#" + endTime+ "#,"+fieldEquipmentName+"='"+eqName+"'";
            updSql=updSql+" where "+fieldOrderNo+"='"+orderNo+"' and "+fieldProcessSeq+"="+processSeq;
            conn=app.getConn();
            cursorA = exec(conn,updSql);
            close(cursorA);
            close(conn);
        end
        %%updage job plan according to the optimization result
        function doUpdatingJobPlanByOptimizationResult(app,ProblemStruct,L3Info,htSize,eqDataName,bestSolution)
                    
            % [~,sumOper]=size(bestSolution);          
            jobNum=ProblemStruct.iJobs;
            jobInfo=ProblemStruct.jobInfo;
            machineNum=ProblemStruct.iEQs;
            operaVec=ProblemStruct.operaNumVec;
            candidateMachine=ProblemStruct.candidateMachine;
            [makespan,curJobTime,machineTable,discretePostion]=doComputingFJSPMakespan(bestSolution,machineNum,jobNum,jobInfo,operaVec,candidateMachine);
            [~,m]=size(machineTable);
            selectedDate=app.DatePicker_startTime.Value;
            % currentDateTime = datetime(selectedDate.Year, selectedDate.Month, selectedDate.Day, ...
            %     hour(datetime('now')), minute(datetime('now')), second(datetime('now')));
            % 
            % currentTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            currentTime = datetime(selectedDate, 'Format', 'yyyy-MM-dd HH:mm:ss');
            for i=1:m
                eqName=eqDataName(i);
                tmpMachineInfo=machineTable{i};
                [~,sizeMachine]=size(tmpMachineInfo);
                for j=1:sizeMachine

                    tmpM=tmpMachineInfo(j);
                    iJob=tmpM.job;
                    if(iJob>0)
                        orderNo=L3Info(iJob);
                        processSeq=tmpM.opera;
                        iStart=tmpM.start;
                        iEnd=tmpM.end;
                        startTime = currentTime + minutes(iStart);
                        endTime = currentTime + minutes(iEnd);
                        updatingJobPlanOperationTime(app,startTime,endTime,orderNo,processSeq,eqName);
                    end
                end
            end


        end
        %%Call the FJSP scheduling engine
        function [GlobalMins1,GlobalSolution,tim] = doInvkingFJSPSchedulingEngine(app,problemStruct)
            algoFunc=app.DropDown_algo.Value;
            max_eva=app.EditField_maxEva.Value
            max_eva=double(max_eva);
            problemStruct.Maxeva=max_eva;
            %%kpi Functions
            % functionName=app.DropDown_kpi.Value;
            %             functionName='doComputingMakespan';
            problemStruct.objType=1;%%1;%1:makespan; 2:totaltime;
            tic;
            [GlobalMins1,GlobalSolution]=feval(algoFunc,problemStruct);
            tim=toc;

        end
        %%Log messages
        function doLogging(app,strMessage)
            strTime=(datestr(now,'yyyy-mm-dd HH:MM:SS'));
            strMessage=strcat(strTime,strMessage);
            app.Label_message.Text=strMessage;
        end
        %%Read data based on SQL；
        function results = doReadingProcessTableDataBySql(app,sql)
            conn=app.getConn();
            %             exec(conn,'quick');
            curs=exec(conn,sql);
            curs=fetch(curs);
            results=curs.Data;
            close(curs);
            close(conn);

        end
        %%read equipments according to craft detail ID
        function [eqTimes,eqIDs,eqNames]=doReadingEQs(app,craftDetID)
            try
                % 1. connect to database
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                % 2. Query the "equipment_name" field                
                tableName=app.LangManager.getText('table.processDetailEquipment.name');
                tableName=string(tableName);
                fieldEquipmentName=app.LangManager.getText('field.PDE.EquipmentName');
                fieldEquipmentName=string(fieldEquipmentName);
                fieldEquipmentID=app.LangManager.getText('field.PDE.EquipmentID');
                fieldEquipmentID=string(fieldEquipmentID);
                fieldProcessDetailID=app.LangManager.getText('field.PDE.ProcessDetailID');
                fieldProcessDetailID=string(fieldProcessDetailID);
                fieldProcessTime=app.LangManager.getText('field.PDE.ProcessTime');
                fieldProcessTime=string(fieldProcessTime);
                sqlQuery = 'SELECT '+fieldEquipmentName+','+fieldEquipmentID+','+fieldProcessTime+' FROM '+tableName+' where '+fieldProcessDetailID+'='+string(craftDetID);
                % Read the "Device Name" column from the equipment table
                curs = exec(conn, sqlQuery);
                curs = fetch(curs);  % Retrieve query results

                % 3. Convert query results to dropdown list options
                if ~isempty(curs.Data)
                    deviceNames = (curs.Data);
                    [m,n]=size(deviceNames);
                    eqTimes="";
                    eqIDs="";
                    eqNames="";
                    if (m>0)
                        for i=1:m
                            eqName=(cell2str(app,deviceNames(i,1)));
                            eqID=(cell2str(app,deviceNames(i,2)));
                            eqTime=(cell2str(app,deviceNames(i,3)));
                            eqTimes=eqTimes+eqTime+',';
                            eqIDs=eqIDs+eqID+',';
                            eqNames=eqNames+eqName+',';
                        end
                        charEqNames=char(eqNames);
                        LenEqNames=length(charEqNames);
                        charEqNames=charEqNames(1:LenEqNames-1);
                        charEqTime=char(eqTimes);
                        LenTime=length(charEqTime);
                        charEqTime=charEqTime(1:LenTime-1);
                        charEqIDs=char(eqIDs);
                        LenID=length(charEqIDs);
                        charEqIDs=charEqIDs(1:LenID-1);
                        eqTimes=string(charEqTime);
                        eqIDs=string(charEqIDs);
                        eqNames=string(charEqNames);
                    end

                end

                % 4. Close the cursor and database connection (release resources)
                close(curs);
                close(conn);

            catch err
                % Catch exceptions and prompt

                dialogLoadEqListFailed=app.LangManager.getText('dialog.loadEqListFailed');
                dialogLoadEqListFailed=string(dialogLoadEqListFailed);
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                dialogLoadFailedr=app.LangManager.getText('dialog.loadFailed');
                dialogLoadFailedr=string(dialogLoadFailedr);
                uialert(app.SIS_APS, [dialogLoadEqListFailed err.message], dialogError);
                app.DropDown_craftNo.Items = {dialogLoadFailedr};
            end
        end
        %Load data for the process code in the order interface
        function doLoadingDataForCraftNo(app)
            try
                % 1. Connect to database
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                tableName=app.LangManager.getText('table.processMaster.name');
                tableName=string(tableName);
                fieldProcessCode=app.LangManager.getText('field.ProcessMaster.ProcessCode');
                fieldProcessCode=string(fieldProcessCode);
                sqlQuery = 'SELECT '+fieldProcessCode+' FROM '+tableName;  
                curs = exec(conn, sqlQuery);
                curs = fetch(curs);  % get query result

                % 3. convert the result to dropdown items 
                if ~isempty(curs.Data)
                    deviceNames = (curs.Data);
                    
                    app.DropDown_craftNo.Items = deviceNames;  % Assign it to the dropdown list
                    app.DropDown_craftNo.Value = deviceNames{1};  % Default to select the first option
                else
                    dialogNoProcessData=app.LangManager.getText('dialog.noProcessData');
                    dialogNoProcessData=string(dialogNoProcessData);
                    app.DropDown_craftNo.Items = {dialogNoProcessData};
                end

                % 4. Close the cursor and database connection (release resources)
                close(curs);
                close(conn);

            catch err
                % Catch exceptions and prompt
                dialogLoadFailed=app.LangManager.getText('dialog.loadFailed');
                dialogLoadFailed=string(dialogLoadFailed);
                dialogLoadCraftListFailed=app.LangManager.getText('dialog.loadCraftListFailed');
                dialogLoadCraftListFailed=string(dialogLoadCraftListFailed);
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, [dialogLoadCraftListFailed err.message], dialogError);
                app.DropDown_craftNo.Items = {dialogLoadFailed};
            end
        end
       
        function doLoadingDataForCraftDet(app)
            % 1. Get the selected row in the table
            selection = app.UITable_craft.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            % selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_order.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_order.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            craftData = app.UITable_craft.Data;
            % craftID = craftData(selectedRowIdx, 1);        % 
            selectedCraftNo = craftData(selectedRowIdx, 2);    % 
            selectedCraftNo=cell2mat(selectedCraftNo);

            % 3. Query the detail data corresponding to this process
       
            tableName=app.LangManager.getText('table.processRouteDetail.name');
            tableName=string(tableName);
            fieldID=app.LangManager.getText('field.PRD.ID');
            fieldID=string(fieldID);
            fieldProcessSeq=app.LangManager.getText('field.PRD.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldProcessName=app.LangManager.getText('field.PRD.ProcessName');
            fieldProcessName=string(fieldProcessName);
            fieldProcessCode=app.LangManager.getText('field.PRD.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            query = sprintf("SELECT "+fieldID+","+fieldProcessSeq+","+fieldProcessName+" FROM "+tableName+" WHERE "+fieldProcessCode+" = '%s'", selectedCraftNo);

            dbConn= app_SISAPS.getConn();
            detailData = fetch(dbConn, query); % 
            app.UITableCraftDet.ColumnName={fieldID,fieldProcessSeq,fieldProcessName};
            % 4. Update the process details list
            app.UITableCraftDet.Data = detailData;
            app.UITableCraftEq.Data = [];
        end
        %%Load data for the process equipment details
        function doLoadingDataForCraftDetEq(app)
          
            selection = app.UITableCraftDet.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            % selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITableCraftDet.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITableCraftDet.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            craftData = app.UITableCraftDet.Data;
            selectedCraftDetId = craftData(selectedRowIdx, 1);    % ID
            selectedCraftDetId=table2cell(selectedCraftDetId);
            selectedCraftDetId=cell2mat(selectedCraftDetId);

            % 3. Query the detail equipment data corresponding to this process
            tableName=app.LangManager.getText('table.processDetailEquipment.name');
            tableName=string(tableName);
            fieldEquipmentName=app.LangManager.getText('field.PDE.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);
            fieldID=app.LangManager.getText('field.PDE.ID');
            fieldID=string(fieldID);
            fieldProcessDetailID=app.LangManager.getText('field.PDE.ProcessDetailID');
            fieldProcessDetailID=string(fieldProcessDetailID);
            fieldProcessTime=app.LangManager.getText('field.PDE.ProcessTime');
            fieldProcessTime=string(fieldProcessTime);
            query = sprintf("SELECT "+fieldID+","+fieldEquipmentName+","+fieldProcessTime+" FROM "+tableName+" WHERE "+fieldProcessDetailID+" = %d", selectedCraftDetId);

            dbConn= app_SISAPS.getConn();
            detailData = fetch(dbConn, query); % 
            app.UITableCraftEq.ColumnName={fieldID,fieldEquipmentName,fieldProcessTime};
            % 4. Update the process details list
            app.UITableCraftEq.Data = detailData;

        end
        function doAddingEqToCraft(app)
            % 1. Get the selected row in the table
            selection = app.UITableCraftDet.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            % % selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITableCraftDet.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITableCraftDet.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize the selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            craftDetData = app.UITableCraftDet.Data;
            craftDetID = craftDetData(selectedRowIdx, 1);
            craftDetID=table2cell(craftDetID);
            craftDetID=cell2mat(craftDetID);
            conn = [];
            curs = [];
            try
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end
                eqName=app.DropDown_Eq.Value;
                eqIndex=app.DropDown_Eq.ValueIndex;
                eqId=app.DropDown_eqIDTmp.Items(eqIndex);
                eqId=str2double(eqId);

                processTime=app.EditField_eqTime.Value;
                currentTime = datetime('now');
                % 

                createDate = char(currentTime, 'yyyy-MM-dd HH:mm:ss'); % 

                % 
                tableName=app.LangManager.getText('table.processDetailEquipment.name');
                tableName=string(tableName);
                fieldEquipmentName=app.LangManager.getText('field.PDE.EquipmentName');
                fieldEquipmentName=string(fieldEquipmentName);
                fieldEquipmentID=app.LangManager.getText('field.PDE.EquipmentID');
                fieldEquipmentID=string(fieldEquipmentID);
                fieldCreateTime=app.LangManager.getText('field.PDE.CreateTime');
                fieldCreateTime=string(fieldCreateTime);
                fieldProcessDetailID=app.LangManager.getText('field.PDE.ProcessDetailID');
                fieldProcessDetailID=string(fieldProcessDetailID);
                fieldProcessTime=app.LangManager.getText('field.PDE.ProcessTime');
                fieldProcessTime=string(fieldProcessTime);
                sqlInsert = sprintf(...
                    'INSERT INTO '+tableName+' ('+fieldProcessDetailID+','+fieldEquipmentName+','+fieldEquipmentID+','+fieldProcessTime+','+fieldCreateTime+') VALUES (%d,''%s'',%d,%d,#%s#)', ...
                    ...
                    craftDetID, ...
                    strrep(eqName, '''', ''''''), ...
                    eqId,...
                    processTime, ...
                    createDate);

                % 
                curs = exec(conn, sqlInsert);
                fetch(curs);
                commit(conn);
                
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, 'Adding equipment successfully！', dialogOperationCompleted, 'Icon', 'success');
       
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                %%
                doLoadingDataForCraftDetEq(app);

            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

              
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to update：' err.message], dialogError, 'Icon', 'error');
                disp(['update exception：' err.message]);
            end
        end
       
        function doAddingProcessToCraftDet(app)
            % Get the selected row in the table
            selection = app.UITable_craft.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_craft.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_craft.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize the selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % Get selected row data from the matrix
            craftData = app.UITable_craft.Data;
            craftNo = craftData(selectedRowIdx, 2); %%
            craftNo=cell2mat(craftNo);
            conn = [];
            curs = [];
            try
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end
                processName=app.DropDown_process.Value;
                processOrder=app.EditField_processOrder.Value;
                if (processOrder<1)
                    dialogProcessSeqGreaterThan0=app.LangManager.getText('dialog.processSeqGreaterThan0');
                    dialogProcessSeqGreaterThan0=string(dialogProcessSeqGreaterThan0);
                    dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                    dialogOperationTip=string(dialogOperationTip);
                    uialert(app.SIS_APS, dialogProcessSeqGreaterThan0, dialogOperationTip, 'Icon', 'info');
                    return;
                end
                currentTime = datetime('now');
                % 

                createDate = char(currentTime, 'yyyy-MM-dd HH:mm:ss'); % 

                % 
                tableName=app.LangManager.getText('table.processRouteDetail.name');
                tableName=string(tableName);
                fieldCreateTime=app.LangManager.getText('field.PRD.ProcessCreateTime');
                fieldCreateTime=string(fieldCreateTime);
                fieldProcessSeq=app.LangManager.getText('field.PRD.ProcessSeq');
                fieldProcessSeq=string(fieldProcessSeq);
                fieldProcessName=app.LangManager.getText('field.PRD.ProcessName');
                fieldProcessName=string(fieldProcessName);
                fieldProcessCode=app.LangManager.getText('field.PRD.ProcessCode');
                fieldProcessCode=string(fieldProcessCode);
                sqlInsert = sprintf(...
                    'INSERT INTO '+tableName+' ('+fieldProcessCode+','+fieldProcessName+','+fieldProcessSeq+','+fieldCreateTime+') VALUES (''%s'',''%s'',%d,#%s#)', ...
                    ...
                    strrep(craftNo, '''', ''''''), ...
                    strrep(processName, '''', ''''''), ...
                    processOrder, ...
                    createDate);

             
                curs = exec(conn, sqlInsert);
                fetch(curs);
                commit(conn);

                % refreshCraftTable(app); 
                
                dialogProcessDetailAddedSuccess=app.LangManager.getText('dialog.processDetailAddedSuccess');
                dialogProcessDetailAddedSuccess=string(dialogProcessDetailAddedSuccess);
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, dialogProcessDetailAddedSuccess, dialogOperationCompleted, 'Icon', 'success');
                % delete(editFig); 


                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                %%
                doLoadingDataForCraftDet(app);

            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

                
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to add：' err.message], dialogError, 'Icon', 'error');
                disp(['add exception：' err.message]);
            end
        end
        %%
        function doLoadingDataForCraftProcess(app)
            try
                % 
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                %
                tableName=app.LangManager.getText('table.process.name');
                tableName=string(tableName);
                fieldProcessName=app.LangManager.getText('field.Process.ProcessName');
                fieldProcessName=string(fieldProcessName);
                sqlQuery = 'SELECT '+fieldProcessName+' FROM '+tableName;  %
                curs = exec(conn, sqlQuery);
                curs = fetch(curs);  % 

                % 
                if ~isempty(curs.Data)
                    deviceNames = (curs.Data);
                    % deviceNames = table2cell(curs.Data);  % 
                    app.DropDown_process.Items = deviceNames;  % Assign it to the dropdown list
                    app.DropDown_process.Value = deviceNames{1};  % Default to select the first option
                else
                    app.DropDown_process.Items = {'no equipment data'};
                end

                %  Close the cursor and database connection (release resources)
                close(curs);
                close(conn);

            catch err
                % 
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to load equipment：' err.message], dialogError);
                app.DropDown_process.Items = {'failure to load'};
            end
        end
        %%
        function doAddingDataForCraftEq(app)
            try
                % 
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                % 
                tableName=app.LangManager.getText('table.equipment.name');
                tableName=string(tableName);
                fieldID=app.LangManager.getText('field.Equipment.ID');
                fieldID=string(fieldID);
                fieldEquipmentName=app.LangManager.getText('field.Equipment.EquipmentName');
                fieldEquipmentName=string(fieldEquipmentName);
                sqlQuery = 'SELECT '+fieldEquipmentName+','+fieldID+' FROM '+tableName;  % 读取设备表的"设备名称"列
                curs = exec(conn, sqlQuery);
                curs = fetch(curs);  % 

                % 
                if ~isempty(curs.Data)
                    deviceNamesArr = (curs.Data);
                    deviceNames=deviceNamesArr(:,1);
                    deviceIDs=string(deviceNamesArr(:,2));
                    % deviceNames = table2cell(curs.Data);  % 
                    app.DropDown_Eq.Items = deviceNames;  % Assign it to the dropdown list
                    app.DropDown_Eq.Value = deviceNames{1};  % Default to select the first option
                    app.DropDown_eqIDTmp.Items=deviceIDs;
                    app.DropDown_eqIDTmp.Value=deviceIDs{1};
                else
                    app.DeviceNameDropdown.Items = {'no equipment data'};
                end

                % 4. Close the cursor and database connection (release resources)
                close(curs);
                close(conn);

            catch err
                % 
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to load equipment data：' err.message], dialogError);
                app.DropDown_Eq.Items = {'failure to load'};
            end
        end
        function saveModifiedCraft(app, editFig, craftID, txtCraftDesc, txtCraftVersion)
  
            newCraftDesc = txtCraftDesc.Value;
            newCraftVersion = txtCraftVersion.Value;
            % newCreateDate = char(createDate.Value, 'yyyy-MM-dd'); % 
            currentTime = datetime('now');
            

            updateTime = char(currentTime, 'yyyy-MM-dd HH:mm:ss'); % 

            newCraftDesc = string(newCraftDesc{1});
            craftID = string(craftID{1});
            craftID=str2double(craftID);
            newCraftVersion = string(newCraftVersion{1});
    
            if isempty(newCraftDesc) || isempty(newCraftVersion)
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'process description and process version cannot be empty！', dialogError, 'Icon', 'error');
                return;
            end

            
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                % 
                tableName=app.LangManager.getText('table.processMaster.name');
                tableName=string(tableName);
                fieldProcessDesc=app.LangManager.getText('field.ProcessMaster.ProcessDesc');
                fieldProcessDesc=string(fieldProcessDesc);
                fieldVersion=app.LangManager.getText('field.ProcessMaster.Version');
                fieldVersion=string(fieldVersion);
                fieldModifyTime=app.LangManager.getText('field.ProcessMaster.ModifyTime');
                fieldModifyTime=string(fieldModifyTime);
                fieldID=app.LangManager.getText('field.ProcessMaster.ID');
                fieldID=string(fieldID);
                sqlUpdate = sprintf(...
                    'UPDATE '+tableName+' SET '+fieldProcessDesc+' = ''%s'', '+fieldVersion+'= ''%s'', '+fieldModifyTime+' = #%s# WHERE '+fieldID+' = %d', ...
                    strrep(newCraftDesc, '''', ''''''), ...
                    strrep(newCraftVersion, '''', ''''''),...
                    updateTime,...
                    craftID ...
                    );

               
                curs = exec(conn, sqlUpdate);
                fetch(curs);
                commit(conn);
                % refreshCraftTable(app);
                
                dialogCraftUpdatedSuccess=app.LangManager.getText('dialog.craftUpdatedSuccess');
                dialogCraftUpdatedSuccess=string(dialogCraftUpdatedSuccess);
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, dialogCraftUpdatedSuccess, dialogOperationCompleted, 'Icon', 'success');
                delete(editFig); % 


                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                refreshCraftTable(app);

            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

          
                dialogModificationFailed=app.LangManager.getText('dialog.modificationFailed');
                dialogModificationFailed=string(dialogModificationFailed);
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                dialogModificationExceptionDetails=app.LangManager.getText('dialog.modificationExceptionDetails');
                dialogModificationExceptionDetails=string(dialogModificationExceptionDetails);
                uialert(app.SIS_APS, [dialogModificationFailed err.message], dialogError, 'Icon', 'error');
                disp([dialogModificationExceptionDetails err.message]);
            end
        end
        % -
        function saveModifiedOrder(app, editFig, orderID, txtCustomer, numQuantity, txtProduct, dpDate)
            % 
            newCustomer = txtCustomer.Value;
            newQuantity = numQuantity.Value;
            newProduct = txtProduct.Value;
            newDate = char(dpDate.Value, 'yyyy-MM-dd'); % 
            newProduct = string(newProduct{1});
            orderID = string(orderID{1});
            newCustomer = string(newCustomer{1});
            % 
            if isempty(newCustomer) || isempty(newProduct)
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Customer name and product name cannot be empty！', dialogError, 'Icon', 'error');
                return;
            end

            % 3. 连接数据库执行UPDATE
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                % 
                tableName=app.LangManager.getText('table.order.name');
                tableName=string(tableName);
                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);
                fieldProductName=app.LangManager.getText('field.order.ProductName');
                fieldProductName=string(fieldProductName);
                fieldQuantity=app.LangManager.getText('field.order.Quantity');
                fieldQuantity=string(fieldQuantity);
                fieldDeliveryDate=app.LangManager.getText('field.order.DeliveryDate');
                fieldDeliveryDate=string(fieldDeliveryDate);
                fieldCustomerName=app.LangManager.getText('field.order.CustomerName');
                fieldCustomerName=string(fieldCustomerName);
                sqlUpdate = sprintf(...
                    'UPDATE '+tableName+' SET '+fieldCustomerName+' = ''%s'', '+fieldQuantity+' = %d, '+fieldProductName+' = ''%s'', '+fieldDeliveryDate+' = #%s# WHERE '+fieldOrderNo+' = ''%s''', ...
                    strrep(newCustomer, '''', ''''''), ...
                    newQuantity,...
                    strrep(newProduct, '''', ''''''),...
                    newDate,...
                    strrep(string(orderID), '''', '''''') ...
                    );

                % 
                curs = exec(conn, sqlUpdate);
                fetch(curs);
                commit(conn);

                % 
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                dialogModificationSuccess=app.LangManager.getText('dialog.modificationSuccess');
                dialogModificationSuccess=string(dialogModificationSuccess);

                uialert(app.SIS_APS, dialogModificationSuccess, dialogOperationCompleted, 'Icon', 'success');
                delete(editFig); % 


                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                refreshOrderTable(app); % 
            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

                % 
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to update：' err.message],dialogError, 'Icon', 'error');
                disp(['update exception：' err.message]);
            end
        end
        %%
        function saveModifiedEqData(app, editFig, eqNo, eqName, eqMemo, buyDate)
            % 
            newEqNo= eqNo.Value;
            newEqName = eqName.Value;
            newEqMemo = eqMemo.Value;
            newDate = char(buyDate.Value, 'yyyy-MM-dd'); % 
            newEqName = string(newEqName{1});
            newEqNo = string(newEqNo{1});
            newEqMemo = string(newEqMemo{1});
         
            if isempty(newEqNo) || isempty(newEqName)
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Equipment code and equipment name cannot be empty！', dialogError, 'Icon', 'error');
                return;
            end

            
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end

                % 
                tableName=app.LangManager.getText('table.equipment.name');
                tableName=string(tableName);
                fieldEquipmentCode=app.LangManager.getText('field.Equipment.EquipmentCode');
                fieldEquipmentCode=string(fieldEquipmentCode);
                fieldPurchaseDate=app.LangManager.getText('field.Equipment.PurchaseDate');
                fieldPurchaseDate=string(fieldPurchaseDate);
                fieldEquipmentName=app.LangManager.getText('field.Equipment.EquipmentName');
                fieldEquipmentName=string(fieldEquipmentName);
                fieldRemarks=app.LangManager.getText('field.Equipment.Remarks');
                fieldRemarks=string(fieldRemarks);
                sqlUpdate = sprintf(...
                    'UPDATE '+tableName+' SET '+fieldEquipmentName+' = ''%s'', '+fieldRemarks+' = ''%s'', '+fieldPurchaseDate+' = #%s# WHERE '+fieldEquipmentCode+' = ''%s''', ...
                    strrep(newEqName, '''', ''''''), ...
                    strrep(newEqMemo, '''', ''''''),...
                    newDate,...
                    strrep(string(newEqNo), '''', '''''') ...
                    );

                %
                curs = exec(conn, sqlUpdate);
                fetch(curs);
                commit(conn);

                % 
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, 'modification successful！', dialogOperationCompleted, 'Icon', 'success');
                delete(editFig); % 

                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                refreshEquipmentTable(app); % 


            catch err
            
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

         
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to update：' err.message], dialogError, 'Icon', 'error');
                disp(['update exception：' err.message]);
            end
        end
        function refreshCraftTable(app)
            conn=app.getConn();
            tableName=app.LangManager.getText('table.processMaster.name');
            tableName=string(tableName);
            fieldID=app.LangManager.getText('field.ProcessMaster.ID');
            fieldID=string(fieldID);
            fieldProcessCode=app.LangManager.getText('field.ProcessMaster.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            fieldProcessDesc=app.LangManager.getText('field.ProcessMaster.ProcessDesc');
            fieldProcessDesc=string(fieldProcessDesc);
            fieldVersion=app.LangManager.getText('field.ProcessMaster.Version');
            fieldVersion=string(fieldVersion);
            fieldCreateTime=app.LangManager.getText('field.ProcessMaster.CreateTime');
            fieldCreateTime=string(fieldCreateTime);
            sql="select "+fieldID+","+fieldProcessCode+","+fieldProcessDesc+","+fieldVersion+","+fieldCreateTime+" from "+tableName;
            cursorA=exec(conn,sql);
            cursorA=fetch(cursorA);
            DataProcess=cursorA.Data;
            app.UITable_craft.ColumnName={fieldID,fieldProcessCode,fieldProcessDesc,fieldVersion,fieldCreateTime};
            app.UITable_craft.RowName={};
            app.UITable_craft.Data=DataProcess;
            close(cursorA);
            close(conn);
        end
        function refreshJobPlanTable(app)
            schPeriod=app.TextArea_schPeriod.Value;
            orderNo=app.TextArea_orderNoQry.Value;
            schPeriod=string(schPeriod);
            orderNo=string(orderNo);

            strWhere="";
            if strlength(strtrim(schPeriod)) ~= 0
                tableName=app.LangManager.getText('table.order.name');
                tableName=string(tableName);

                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);
                fieldSchedulingCycle=app.LangManager.getText('field.order.SchedulingCycle');
                fieldSchedulingCycle=string(fieldSchedulingCycle);

                strWhere=" where "+fieldOrderNo+" in (select "+fieldOrderNo+" from "+tableName+" where "+fieldSchedulingCycle+" = '"+schPeriod+"')";
                if strlength(strtrim(orderNo)) ~= 0
                    strWhere=strWhere+" and "+fieldOrderNo+" = '"+orderNo+"'";
                end
            else
                if strlength(strtrim(orderNo)) ~= 0
                    fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                    fieldOrderNo=string(fieldOrderNo);
                    strWhere=strWhere+" where "+fieldOrderNo+" = '"+orderNo+"'";
                end
            end
        
            conn=app.getConn();%%
            tableName=app.LangManager.getText('table.jobPlan.name');
            tableName=string(tableName);
            fieldOperationPlanID=app.LangManager.getText('field.OperationPlan.ID');
            fieldOperationPlanID=string(fieldOperationPlanID);
            fieldOrderNo=app.LangManager.getText('field.OperationPlan.OrderNo');
            fieldOrderNo=string(fieldOrderNo);
            fieldProcessCode=app.LangManager.getText('field.OperationPlan.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            fieldProcessName=app.LangManager.getText('field.OperationPlan.ProcessName');
            fieldProcessName=string(fieldProcessName);
            fieldProcessSeq=app.LangManager.getText('field.OperationPlan.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldProcessTimeSet=app.LangManager.getText('field.OperationPlan.ProcessTimeSet');
            fieldProcessTimeSet=string(fieldProcessTimeSet);
            fieldEquipmentNameSet=app.LangManager.getText('field.OperationPlan.EquipmentNameSet');
            fieldEquipmentNameSet=string(fieldEquipmentNameSet);
            fieldOperationSeq=app.LangManager.getText('field.OperationPlan.OperationSeq');
            fieldOperationSeq=string(fieldOperationSeq);
            sql="select "+fieldOperationPlanID+","+fieldOrderNo+","+fieldProcessCode+","+fieldProcessSeq+","+fieldProcessName+","+fieldEquipmentNameSet+","+fieldProcessTimeSet+","+fieldOperationSeq+" from "+tableName;
            sql=sql+strWhere;
            cursorA=exec(conn,sql);
            cursorA=fetch(cursorA);
            orderData=cursorA.Data;
            [nSize,~]=size(orderData);
            labelSum=app.LangManager.getText('label.sum');
            labelSum=string(labelSum);
            labelRecordTip=app.LangManager.getText('label.recordTip');
            labelRecordTip=string(labelRecordTip);
            app.Label_slatMsg.Text=labelSum+" "+string(nSize)+" "+labelRecordTip;
           
            app.UITable_jobPlan.ColumnName={fieldOperationPlanID,fieldOrderNo,fieldProcessCode,fieldProcessSeq,fieldProcessName,fieldEquipmentNameSet,fieldProcessTimeSet,fieldOperationSeq};%%cell leixing
            app.UITable_jobPlan.RowName={};
            app.UITable_jobPlan.Data=orderData;
            close(cursorA);
            close(conn);
        end
      
        function refreshOrderTable(app)
            conn=app.getConn();
            tableName=app.LangManager.getText('table.order.name');
            tableName=string(tableName);
            fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
            fieldOrderNo=string(fieldOrderNo);
            fieldProductName=app.LangManager.getText('field.order.ProductName');
            fieldProductName=string(fieldProductName);
            fieldProcessCode=app.LangManager.getText('field.order.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            fieldDeliveryDate=app.LangManager.getText('field.order.DeliveryDate');
            fieldDeliveryDate=string(fieldDeliveryDate);
            fieldCustomerName=app.LangManager.getText('field.order.CustomerName');
            fieldCustomerName=string(fieldCustomerName);
            fieldCustomerPhone=app.LangManager.getText('field.order.CustomerPhone');
            fieldCustomerPhone=string(fieldCustomerPhone);
            fieldOrderStatus=app.LangManager.getText('field.order.OrderStatus');
            fieldOrderStatus=string(fieldOrderStatus);
            fieldSchedulingCycle=app.LangManager.getText('field.order.SchedulingCycle');
            fieldSchedulingCycle=string(fieldSchedulingCycle);
            fieldRemarks=app.LangManager.getText('field.order.Remarks');
            fieldRemarks=string(fieldRemarks);
            sql="select "+fieldOrderNo+","+fieldProductName+","+fieldProcessCode+","+fieldDeliveryDate+","+fieldCustomerName+","+fieldCustomerPhone+","+fieldOrderStatus+","+fieldSchedulingCycle+","+fieldRemarks+" from "+tableName;
            cursorA=exec(conn,sql);
            cursorA=fetch(cursorA);
            Data=cursorA.Data;
            %            
            app.UITable_order.ColumnName={fieldOrderNo,fieldProductName,fieldProcessCode,fieldDeliveryDate,fieldCustomerName,fieldCustomerPhone,fieldOrderStatus,fieldSchedulingCycle,fieldRemarks};%%cell leixing
            app.UITable_order.RowName={};
            app.UITable_order.Data=Data;
            close(cursorA);
            close(conn);

        end
       
        function refreshEquipmentTable(app)
            conn=app.getConn();
            tableName=app.LangManager.getText('table.equipment.name');
            tableName=string(tableName);
            fieldEquipmentCode=app.LangManager.getText('field.Equipment.EquipmentCode');
            fieldEquipmentCode=string(fieldEquipmentCode);
            fieldPurchaseDate=app.LangManager.getText('field.Equipment.PurchaseDate');
            fieldPurchaseDate=string(fieldPurchaseDate);
            fieldEquipmentName=app.LangManager.getText('field.Equipment.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);
            fieldSupplier=app.LangManager.getText('field.Equipment.Supplier');
            fieldSupplier=string(fieldSupplier);
            fieldRemarks=app.LangManager.getText('field.Equipment.Remarks');
            fieldRemarks=string(fieldRemarks);
            sql="select "+fieldEquipmentCode+","+fieldEquipmentName+","+fieldSupplier+",Format("+fieldPurchaseDate+",'yyyy-mm-dd') as purchaseTime,"+fieldRemarks+" from "+tableName;
            cursorA=exec(conn,sql);
            cursorA=fetch(cursorA);
            DataEQ=cursorA.Data;
           
            app.UITable_equipment.ColumnName={fieldEquipmentCode,fieldEquipmentName,fieldSupplier,fieldPurchaseDate,fieldRemarks};%%cell leixing
            app.UITable_equipment.RowName={};
            app.UITable_equipment.Data=DataEQ;
            close(cursorA);
            close(conn);

        end
        function results = drawGan(app,seq,time,process_time)

          
            [jobs,machines]=size(time);
           
            color1 = [
                0 1 0
                1 0.6 0.07
                1 1 1
                0.7413    0.2845    0.8666
                0.3728    0.4740    0.0278
                1 0 1
                0.3488    0.2631    0.6791
                1 0.38 0
                0.7747    0.5386    0.2652
                1 1 0
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                0.2128    0.7566    0.3076
                ];
            for i=1:machines
                for j=1:jobs
                    number=seq(j);
                    ctime=time(number,i); %
                    need_time = process_time(number,i); %
                    txt=sprintf("%d",number);% 
                    rec=[ctime-need_time,i-0.25,need_time,0.5]; % [x,y,w,h]
                    rectangle( app.UIAxes2,"Position",rec,'LineWidth',0.5,'LineStyle','-','FaceColor',color1(number,:));
                  

                    text( app.UIAxes2,ctime-need_time+0.5,i,txt,"FontSize",8,"FontWeight","bold");%绘制标注信息，注销可以不显示矩形里的J2等等
                    set( app.UIAxes2,"FontSize",12);
                   
                    xlabel( app.UIAxes2,"time","FontSize",12);
                    set( app.UIAxes2,'ytick',0:1:machines);
                    
                    ylabel( app.UIAxes2,"machine","FontSize",12);
                end
            end
        end

        function results = cell2str(app,celldata)
            % celldata is a vector
            % initialization
            [m,~]=size(celldata);
            string=repmat("a",m,1);
            % transform
            for i=1:m
                string(i)=celldata{i};
            end
            results=string;
        end

        function [GlobalMins1,GlobalSolution,tim] = doInvokingAFO(app,popSize,iJobs,iEQs,P,runTimes)%%调用AFO算法

            Ld=0;
            Ud=1;
            %             algoFunc=app.DropDown_algo.Value;
            %             max_eva=app.EditField_limit.Value;
            max_eva=10000;
            problem="problemName";
            max_it=100;
            obj_no=1;

            %             functionName=app.DropDown_kpi.Value;
            functionName="doComputingMakespan";
            %             functionName='doComputingMakespan';
            Func_num=1;
            dim=iJobs;
            ProblemStruct=struct(...
                'PopSize',popSize,...%%20,...%%30,...%%30，20,,50,...%%iEQs*10, ... %%100,50
                'obj_no',obj_no,...
                'Maxeva',max_eva,...%%50000,...%%1000000,...%600000,...%%200000,...%%100000,...%%1000000,...%%50000, 20000...
                'Run', runTimes, ... %%30,10
                'problem',problem,...
                'FunctionName',functionName,...
                'Func_num',Func_num,...
                'Dim',dim, ...
                'iEQs',iEQs, ...
                'Ld',Ld, ...
                'Ud',Ud, ...
                'aa',0.9, ...
                'bb',0.1, ... %%0.2
                'CR',0.1, ...
                'F',0.1,...
                'P',P,...
                'dmax',1e-16,...
                'max_it',max_it ...
                );
            ProblemStruct.fobj=@aim_PFSPInterface;
            %             ProblemStruct.fobj=@doComputingMakespan;

            ProblemStruct.objType=1;%%1;%1:makespan; 2:totaltime;
            ProblemStruct.k=1;
            ProblemStruct.Limit=5;%%100;
            ProblemStruct.ratio=0.6;
            ProblemStruct.scale=0.5;
            ProblemStruct.a=0.9;
            ProblemStruct.b=0.2;
            ProblemStruct.CR=0.5;%%0.1;
            ProblemStruct.F=0.1;
            ProblemStruct.minValue=0;
            tic;
            algoFunc="ABC";
            [GlobalMins1,GlobalSolution]=feval(algoFunc,ProblemStruct);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % 
                % app.LangManager = LanguageManager('chinese');
                app.LangManager = LanguageManager('english');
              
                app.updateUIText();

                % % 
                % app.Data = struct();
                % app.Data.counter = 0;
                %
                % % 
                % app.IsRunning = false;
                % app.StopButton.Enable = 'off';
                %
                % % ）
                % app.Timer = timer(...
                %     'ExecutionMode', 'fixedRate', ...
                %     'Period', 0.1, ...
                %     'TimerFcn', @app.timerCallback);

            catch ME
                % Display an error message on startup failure.
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ...
                    sprintf('Startup failed: %s', ME.message), ...
                    dialogError, 'Icon', 'error');
            end

        end

        % Button pushed function: queryButton_2
        function Button_2Pushed(app, event)

            refreshCraftTable(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            Data1=xlsread('Convergence Curve Data.xlsx','Rec03','B3:B203');%IMTLBO
            Data2=xlsread('Convergence Curve Data.xlsx','Rec03','C3:C203');%IMTLBO-NL
            Data3=xlsread('Convergence Curve Data.xlsx','Rec03','D3:D203');%IMTLBO-NG
            Data4=xlsread('Convergence Curve Data.xlsx','Rec03','E3:E203');%TLBO
       
            y1=Data1';%IMTLBO
            y2=Data2';%IMTLBO-NL
            y3=Data3';%IMTLBO-NG
            y4=Data4';%TLBO

            
            A=plot(app.UIAxes,1:1:201, y4, '-o','color','b','linewidth', 1.5);%设置x(横坐标）,y（纵坐标），线条及标记样式，颜色（曲线和图例默认颜色一致），迭代曲线宽度
            A.MarkerSize = 5;          %marker
            A.MarkerIndices = 1:10:201;%marker
            %             hold on
            app.UIAxes.NextPlot='add';
            %
            B=plot(app.UIAxes,1:1:201, y2, '-s','color','m','linewidth', 1.5);%设置x(横坐标）,y（纵坐标），线条及标记样式，颜色（曲线和图例默认颜色一致），迭代曲线宽度
            B.MarkerSize = 6;          %
            B.MarkerIndices = 1:10:201;%
            %             hold on
            app.UIAxes.NextPlot='add';
           
            C=plot(app.UIAxes,1:1:201, y3, '-<','color','g','linewidth', 1.5);%
            C.MarkerSize = 5;          %
            C.MarkerIndices = 1:10:201;%
            %             hold on
            app.UIAxes.NextPlot='add';
            %
            D=plot(app.UIAxes,1:1:201, y1, '-h','color','r','linewidth', 1.5);%
            D.MarkerSize = 5;          %
            D.MarkerIndices = 1:10:201;%

            %
            xlabel(app.UIAxes,"Iterations",'FontName','Times New Roman',"FontSize",14); % xlabel("Iterations",'FontName','Times New Roman',"FontSize",18,"FontWeight","bold");
            ylabel(app.UIAxes,"Makespan",'FontName','Times New Roman',"FontSize",14);   %
            legend(app.UIAxes,'TLBO','IMTLBO-NL','IMTLBO-NG','IMTLBO','FontName','Times New Roman',"FontSize",14);%
            %set(gca,'XLim',[0 200]);%
            %set(gca,"FontSize",14); %

            seq=[6,14,7,1,2,11,8,13,19,5,17,4,3,16,10,15,9,12,18,20];
            process_time=initDateRec03()';
            [~,time]=decodeV2(seq,process_time);
            app.drawGan(seq,time,process_time);


        end

        % Callback function
        function Button_5Pushed(app, event)
            iJob=app.EditField_job.Value;

            iEq=app.EditField_eq.Value;
            strJob=string(iJob);
            msgbox (string(strJob));
        end

        % Callback function
        function Button_6Pushed(app, event)
            %%
            schedulingPeriod=app.TextArea_5.Value;%%
            schedulingPeriod=strtrim(schedulingPeriod);
            if(schedulingPeriod=="")
                msgbox("input scheduling period！");
                return;
            end
            tableName=app.LangManager.getText('table.jobPlan.name');
            tableName=string(tableName);
            fieldOrderNo=app.LangManager.getText('field.OperationPlan.OrderNo');
            fieldOrderNo=string(fieldOrderNo);
            fieldProcessCode=app.LangManager.getText('field.OperationPlan.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            fieldProcessName=app.LangManager.getText('field.OperationPlan.ProcessName');
            fieldProcessName=string(fieldProcessName);
            fieldProcessSeq=app.LangManager.getText('field.OperationPlan.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldProcessTimeSet=app.LangManager.getText('field.OperationPlan.ProcessTimeSet');
            fieldProcessTimeSet=string(fieldProcessTimeSet);
            fieldEquipmentNameSet=app.LangManager.getText('field.OperationPlan.EquipmentNameSet');
            fieldEquipmentNameSet=string(fieldEquipmentNameSet);
            fieldOperationSeq=app.LangManager.getText('field.OperationPlan.OperationSeq');
            fieldOperationSeq=string(fieldOperationSeq);
            fieldProcessTime=app.LangManager.getText('field.OperationPlan.ProcessTime');
            fieldProcessTime=string(fieldProcessTime);
            orderTableName=app.LangManager.getText('table.order.name');
            orderTableName=string(orderTableName);
            fieldSchedulingCycle=app.LangManager.getText('field.order.SchedulingCycle');
            fieldSchedulingCycle=string(fieldSchedulingCycle);
            fieldOrderOrderNo=app.LangManager.getText('field.order.OrderNo');
            fieldOrderOrderNo=string(fieldOrderOrderNo);
            stateOrderStaus=app.LangManager.getText('state.orderStaus');
            stateOrderStaus=string(stateOrderStaus);
            sql="select "+fieldOrderNo+","+fieldProcessCode+","+fieldProcessSeq+","+fieldProcessName+","+fieldEquipmentNameSet+","+fieldProcessTimeSet+","+fieldOperationSeq+" from "+tableName;
            sql=sql+" where "+fieldOrderNo+"  in(SELECT "+fieldOrderOrderNo+" from "+orderTableName+" where "+fieldSchedulingCycle+" = '"+schedulingPeriod+"')";
            sql=sql+"  order by "+fieldOrderNo+","+fieldProcessSeq;%%
            conn=app.getConn();
            cursor=exec(conn,sql);%%SQL

            cursorData=fetch(cursor);
            jobData=cursorData.Data;
            [n,m]=size(jobData);
            %  %%%           tmp=[1 2 3 ; 4 5 6];
            app.UITable_9.Data=jobData;
            app.UITable_9.ColumnName={fieldOrderNo,fieldProcessSeq,fieldProcessName,fieldProcessTime,fieldProcessCode,fieldOperationSeq};
            close(cursor);
            close(conn);
            iCount=1;%%
            htL3 = java.util.Hashtable;% 
            htSize=java.util.Hashtable;%
            for i=1:n
                slatL3No=cell2str(app,jobData(i,1));
                slatSeq=double(cell2str(app,jobData(i,1)));
                strSlatEq=cell2str(app,jobData(i,3));
                strSlatTime=cell2str(app,jobData(i,4));
                if(contains(strSlatEq,','))
                    slatEq=split(",");%%
                else
                    slatEq=double(strSlatEq);
                end
                if(contains(strSlatTime,','))
                    slatTime=split(",");%%
                else
                    slatTime=double(strSlatTime);
                end

                if(htL3.containsKey(slatL3No))
                    tmpSlatTime=htL3.get(slatL3No);
                    tmpSlatTime=tmpSlatTime';
                    tmpSlatTime=[tmpSlatTime slatTime];
                    htL3.put(slatL3No,tmpSlatTime);
                    iSize=htSize.get(slatL3No);
                    iSize=iSize+1;
                    htSize.put(slatL3No,iSize);
                else
                    htL3.put(slatL3No,[slatTime]);
                    htSize.put(slatL3No,1);
                    arrL3No(iCount,1)=slatL3No;
                    iCount=iCount+1;
                end

            end
            %                     numberOfJobs=htL3.size();
            %% 
            % 		            ArrayList<String> list = ArrayList(htL3.keySet());
            % 		            list.sort(null);

            jobArr=[];
            L3Arr=[];
            e = htL3.keys();
            [numberOfJobs,~]=size(arrL3No);
            originalArr=[];
            originalStrSeq="";
            for L=1:numberOfJobs
                %                     while (e.hasMoreElements())
                tmpKey=arrL3No(L);
                %                         tmpKey=e.nextElement();
                tmpArr= htL3.get(tmpKey);
                L3Arr=[L3Arr string(tmpKey)];
                jobArr=[jobArr tmpArr];
                originalArr(L)=L;
                originalStrSeq=originalStrSeq+string(L)+",";
            end
            [iEQs,iJobs]=size(jobArr);
            %             L3Arr=L3Arr';
            %             jobArr=jobArr';
            %%
            popSize=20;
            runTimes=2;
            orginalCmax=doComputingPFSPObj(originalArr,jobArr,iJobs,iEQs);
            [GlobalMins1,GlobalSolution] = doInvokingAFO(app,popSize,iJobs,iEQs,jobArr,runTimes);%%调用AFO算法
            [minVal,minInd]=min(GlobalMins1(:,end));
            bestSol=GlobalSolution(minInd,:);
            bestSol=doReadingSchedulingBySPv(bestSol);
            bestSol=cellstr(string(bestSol));
            Len=length(bestSol);
            strSeq="";
            for i=1:Len
                strSeq=strSeq+bestSol(i)+",";
                bestSolArr(i)=str2double(bestSol(i));
            end
            app.EditField_optimizedSof.Value=char(strSeq);

            app.EditField_2.Value=string(minVal);

            app.EditField_bestObj.Value=(originalStrSeq);
            app.EditField_4.Value=string(orginalCmax);
            saveTime=orginalCmax-minVal;
            % app.EditField_20.Value=string(orginalCmax-minVal);
            percentValue=(saveTime/orginalCmax) *100;
            app.Gauge.Value=(percentValue);
            msgbox("排产完毕！");
        end

        % Callback function
        function Button_4Pushed(app, event)

           
        end

        % Callback function
        function Button_10Pushed(app, event)
           
        end

        % Cell edit callback: UITable_order
        function UITable_orderCellEdit(app, event)
            
        end

        % Button pushed function: queryButton
        function queryButtonPushed(app, event)
      
            doLoadingDataForCraftNo(app);
            refreshOrderTable(app);

        end

        % Button pushed function: queryButton_4
        function queryButton_4Pushed(app, event)
            refreshEquipmentTable(app);

        end

        % Cell edit callback: UITable_equipment
        function UITable_equipmentCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: addCraftButton
        function addCraftButtonPushed(app, event)
                
            craftNo = app.EditField_craftNo.Value;          % 
            craftDesc=app.EditField_craftDesc.Value;%
            craftVersion = app.EditField_craftVersion.Value;    % 
            currentTime = datetime('now');
           

            createDate = char(currentTime, 'yyyy-MM-dd HH:mm:ss'); % 

            
            if isempty(craftNo) || isempty(craftVersion)||isempty(craftDesc)
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Process Code, Process Description, and Version Number cannot be empty！', dialogError, 'Icon', 'error');
                return;
            end
           
            try               

                conn=app.getConn();
                % 
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end


                % 
                tableName=app.LangManager.getText('table.processMaster.name');
                tableName=string(tableName);
                fieldProcessCode=app.LangManager.getText('field.ProcessMaster.ProcessCode');
                fieldProcessCode=string(fieldProcessCode);
                fieldProcessDesc=app.LangManager.getText('field.ProcessMaster.ProcessDesc');
                fieldProcessDesc=string(fieldProcessDesc);
                fieldVersion=app.LangManager.getText('field.ProcessMaster.Version');
                fieldVersion=string(fieldVersion);
                fieldCreateTime=app.LangManager.getText('field.ProcessMaster.CreateTime');
                fieldCreateTime=string(fieldCreateTime);
                sqlInsert = sprintf(...
                    'INSERT INTO '+tableName+' ('+fieldProcessCode+','+fieldProcessDesc+', '+fieldVersion+', '+fieldCreateTime+') VALUES (''%s'',''%s'', ''%s'',#%s#)', ...
                    strrep(craftNo, '''', ''''''), ...
                    strrep(craftDesc, '''', ''''''), ...
                    strrep(craftVersion, '''', ''''''), ...
                    createDate);


                % 
                curs = exec(conn, sqlInsert);  % 
                fetch(curs);                   % 
                commit(conn);                  % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                refreshCraftTable(app); % 
                % 

                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogCraftAddedSuccess=app.LangManager.getText('dialog.craftAddedSuccess');
                uialert(app.SIS_APS, dialogCraftAddedSuccess, dialogOperationCompleted, 'Icon', 'success');
                % 
                app.EditField_craftNo.Value = '';
                app.EditField_craftVersion.Value = '';

                % 
            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end


                dialogError=app.LangManager.getText('dialog.error');
                dialogInsertFailed=app.LangManager.getText('dialog.insertFailed');
                uialert(app.SIS_APS, [dialogInsertFailed err.message], dialogError, 'Icon', 'error');
            end

        end

        % Cell edit callback: UITable_craft
        function UITable_craftCellEdit(app, event)
            
        end

        % Callback function
        function Button_41Pushed(app, event)
            
        end

        % Callback function
        function Button_26Pushed(app, event)
            close(app.SIS_APS);
            close all; %%exit system
        end

        % Callback function
        function Button_42Pushed(app, event)
            %%query craft
            selection = app.UITable_craft.Selection;
            if isempty(selection)
                msgbox("Please select the machining process record to view！");
                return;
            end
            app.TabGroup.SelectedTab=app.Tab_11;%%Switch to details；
            selection = app.UITable_craft.Selection;
            process_name=cell2str(app,app.UITable_craft.Data(selection,2));
            %             msgbox(process_name);
            conn=app.getConn();
            tableName=app.LangManager.getText('table.processRouteDetail.name');
            tableName=string(tableName);
            fieldID=app.LangManager.getText('field.PRD.ID');
            fieldID=string(fieldID);
            fieldProcessSeq=app.LangManager.getText('field.PRD.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldProcessName=app.LangManager.getText('field.PRD.ProcessName');
            fieldProcessName=string(fieldProcessName);
            fieldProcessCode=app.LangManager.getText('field.PRD.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            sql="select "+fieldID+","+fieldProcessSeq+","+fieldProcessName+" from "+tableName+" where "+fieldProcessCode+"='"+process_name+"'";
            cursorA=exec(conn,sql);
            cursorA=fetch(cursorA);
            processData=cursorA.Data;
          
            app.UITable_7.ColumnName={fieldID,fieldProcessSeq,fieldProcessName};%%cell leixing
            app.UITable_7.RowName={};
            app.UITable_7.Data=processData;
            close(cursorA);
            close(conn);
        end

        % Button pushed function: generateplanButton
        function generateplanButtonPushed(app, event)
            %%generate job plan 
            %%query the order no
            selection = app.UITable_order.Selection;
            if isempty(selection)
                dialogSelectOrderRecordTip=app.LangManager.getText('dialog.selectOrderRecordTip');
                dialogSelectOrderRecordTip=string(dialogSelectOrderRecordTip);
                msgbox(dialogSelectOrderRecordTip);
                return;
            end

            selection = app.UITable_order.Selection;
            order_status=cell2str(app,app.UITable_order.Data(selection,7));%%read the status of the order

            stateOrderStaus=app.LangManager.getText('state.orderStaus');
            stateOrderStaus=string(stateOrderStaus);
            if (order_status==stateOrderStaus)
                dialogJobPlanGeneratedTip=app.LangManager.getText('dialog.jobPlanGeneratedTip');
                dialogJobPlanGeneratedTip=string(dialogJobPlanGeneratedTip);
                msgbox(dialogJobPlanGeneratedTip);
                return;
            end
            order_no=cell2str(app,app.UITable_order.Data(selection,1));%%read the order no
            craft_no=cell2str(app,app.UITable_order.Data(selection,3));%%read the process no
            tableName=app.LangManager.getText('table.processRouteDetail.name');
            tableName=string(tableName);
            fieldID=app.LangManager.getText('field.PRD.ID');
            fieldID=string(fieldID);
            fieldProcessSeq=app.LangManager.getText('field.PRD.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldProcessName=app.LangManager.getText('field.PRD.ProcessName');
            fieldProcessName=string(fieldProcessName);
            fieldProcessCode=app.LangManager.getText('field.PRD.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);

            sqlCraft="select "+fieldProcessSeq+","+fieldProcessName+","+fieldProcessCode+","+fieldID+" from "+tableName+" where "+fieldProcessCode+"='"+craft_no+"'";
            conn=app.getConn();
            
            conn.AutoCommit='off';
            try
                cursorA=exec(conn,sqlCraft);
                cursorA=fetch(cursorA);
                cellCradet=cursorA.Data;
                [m,n]=size(cellCradet);

                for i=1:m
                    processSeq=double(cell2str(app,cellCradet(i,1)));
                    processName=(cell2str(app,cellCradet(i,2)));
                    craftDetID=cell2mat(cellCradet(i,4));
                    craftDetID=double(craftDetID);

                    [eqTimes,eqIDs,eqNames]=doReadingEQs(app,craftDetID);
                    tableName=app.LangManager.getText('table.jobPlan.name');
                    tableName=string(tableName);
                    fieldOrderNo=app.LangManager.getText('field.OperationPlan.OrderNo');
                    fieldOrderNo=string(fieldOrderNo);
                    fieldProcessCode=app.LangManager.getText('field.OperationPlan.ProcessCode');
                    fieldProcessCode=string(fieldProcessCode);
                    fieldProcessName=app.LangManager.getText('field.OperationPlan.ProcessName');
                    fieldProcessName=string(fieldProcessName);
                    fieldProcessSeq=app.LangManager.getText('field.OperationPlan.ProcessSeq');
                    fieldProcessSeq=string(fieldProcessSeq);
                    fieldProcessTimeSet=app.LangManager.getText('field.OperationPlan.ProcessTimeSet');
                    fieldProcessTimeSet=string(fieldProcessTimeSet);
                    fieldEquipmentNameSet=app.LangManager.getText('field.OperationPlan.EquipmentNameSet');
                    fieldEquipmentNameSet=string(fieldEquipmentNameSet);
                    fieldEquipmentIDSet=app.LangManager.getText('field.OperationPlan.EquipmentIDSet');
                    fieldEquipmentIDSet=string(fieldEquipmentIDSet);
                    sqlAdd="insert into "+tableName+"("+fieldOrderNo+","+fieldProcessCode+","+fieldProcessName+","+fieldProcessSeq+","+fieldProcessTimeSet+","+fieldEquipmentNameSet+","+fieldEquipmentIDSet+") values('"+order_no+"','"+craft_no+"','"+processName+...
                        "',"+processSeq+",'"+eqTimes+"','"+eqNames+"','"+eqIDs+"')";

                    curs = exec(conn,sqlAdd);
                end
                orderTableName=app.LangManager.getText('table.order.name');
                orderTableName=string(orderTableName);
                fieldOrderStatus=app.LangManager.getText('field.order.OrderStatus');
                fieldOrderStatus=string(fieldOrderStatus);
                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);
                stateOrderStaus=app.LangManager.getText('state.orderStaus');
                stateOrderStaus=string(stateOrderStaus);

                updSql="update "+orderTableName+" set "+fieldOrderStatus+"='"+stateOrderStaus+"' where "+fieldOrderNo+"='"+order_no+"'";
                curs = exec(conn,updSql);
                % commit the transaction
                commit(conn);
                close(cursorA);
                dialogScheduleGeneratedSuccess=app.LangManager.getText('dialog.scheduleGeneratedSuccess');
                dialogScheduleGeneratedSuccess=string(dialogScheduleGeneratedSuccess);

                msgbox(dialogScheduleGeneratedSuccess);
            catch exception
                % if failure rollback the transaction
                rollback(conn);
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                disp([dialogError exception.message]);
                msgbox(exception.message);
            end
            
            conn.AutoCommit='on';
            close(conn);
            refreshOrderTable(app);

        end

        % Button pushed function: queryButton_3
        function queryButton_3Pushed(app, event)
            refreshJobPlanTable(app);
            
        end

        % Button pushed function: Button_qryJobplan
        function Button_qryJobplanPushed(app, event)

            schedulingPeriod=app.TextArea_5.Value;%%read the scheduling period
            schedulingPeriod=strtrim(schedulingPeriod);
            if(schedulingPeriod=="")
                dialogInputSchedulingPeriod=app.LangManager.getText('dialog.inputSchedulingPeriod');
                dialogInputSchedulingPeriod=string(dialogInputSchedulingPeriod);
                msgbox(dialogInputSchedulingPeriod);
                return;
            end
            tableName=app.LangManager.getText('table.jobPlan.name');
            tableName=string(tableName);
            fieldOrderNo=app.LangManager.getText('field.OperationPlan.OrderNo');
            fieldOrderNo=string(fieldOrderNo);
            fieldProcessCode=app.LangManager.getText('field.OperationPlan.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            fieldProcessName=app.LangManager.getText('field.OperationPlan.ProcessName');
            fieldProcessName=string(fieldProcessName);
            fieldProcessSeq=app.LangManager.getText('field.OperationPlan.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldEquipmentName=app.LangManager.getText('field.OperationPlan.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);
            fieldProcessTimeSet=app.LangManager.getText('field.OperationPlan.ProcessTimeSet');
            fieldProcessTimeSet=string(fieldProcessTimeSet);
            fieldEquipmentNameSet=app.LangManager.getText('field.OperationPlan.EquipmentNameSet');
            fieldEquipmentNameSet=string(fieldEquipmentNameSet);

            fieldOperationSeq=app.LangManager.getText('field.OperationPlan.OperationSeq');
            fieldOperationSeq=string(fieldOperationSeq);
            fieldStartTime=app.LangManager.getText('field.OperationPlan.StartTime');
            fieldStartTime=string(fieldStartTime);
            fieldEndTime=app.LangManager.getText('field.OperationPlan.EndTime');
            fieldEndTime=string(fieldEndTime);
            orderTableName=app.LangManager.getText('table.order.name');
            orderTableName=string(orderTableName);
            fieldSchedulingCycle=app.LangManager.getText('field.order.SchedulingCycle');
            fieldSchedulingCycle=string(fieldSchedulingCycle);
            fieldOrderOrderNo=app.LangManager.getText('field.order.OrderNo');
            fieldOrderOrderNo=string(fieldOrderOrderNo);
            sql="select "+fieldOrderNo+","+fieldProcessCode+","+fieldProcessSeq+","+fieldProcessName+","+fieldStartTime+","+fieldEndTime+","+fieldEquipmentName+" from "+tableName;
            sql=sql+" where "+fieldOrderNo+"  in(SELECT "+fieldOrderOrderNo+" from "+orderTableName+" where "+fieldSchedulingCycle+" = '"+schedulingPeriod+"')";
            sql=sql+"  order by "+fieldOrderNo+","+fieldProcessSeq;%%
            conn=app.getConn();
            cursor=exec(conn,sql);%%SQL

            cursorData=fetch(cursor);
            jobData=cursorData.Data;
            [n,m]=size(jobData);
            app.UITable_9.Data=jobData;
            app.UITable_9.ColumnName={fieldOrderNo,fieldProcessCode,fieldProcessSeq,fieldProcessName,fieldStartTime,fieldEndTime,fieldEquipmentName};
            close(cursor);
            close(conn);
        end

        % Button pushed function: Button_addOrder
        function Button_addOrderPushed(app, event)
            %%add new order
            
            orderID = app.edtOrderID.Value;          %order no
            customerName = app.edtCustomer.Value;    % customer name
            quantity = app.edtQuantity.Value;       % quantity
            productName = app.edtProduct.Value;      % product name
            % convert the delivery date to the format to Access
            %deliveryDate = datestr(app.dpDeliveryDate.Value, 'yyyy-mm-dd');
            deliveryDatetime = app.dpDeliveryDate.Value; % datetime type
            deliveryDate = char(deliveryDatetime, 'yyyy-MM-dd'); %Convert to character string with specified format

            % 2. Input validation
            if isempty(orderID) || isempty(customerName) || isempty(productName)
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'order no，customer name，product name not be empty！', dialogError, 'Icon', 'error');
                return;
            end
            if quantity <= 0
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'quantity must be a positive number！', dialogError, 'Icon', 'error');
                return;
            end

            try
                conn=app.getConn();
                % check if connect sucessfully
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end
                % 4.  Construct SQL INSERT statement                
                tableName=app.LangManager.getText('table.order.name');
                tableName=string(tableName);
                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);

                fieldProductName=app.LangManager.getText('field.order.ProductName');
                fieldProductName=string(fieldProductName);
                fieldQuantity=app.LangManager.getText('field.order.Quantity');
                fieldQuantity=string(fieldQuantity);
                fieldDeliveryDate=app.LangManager.getText('field.order.DeliveryDate');
                fieldDeliveryDate=string(fieldDeliveryDate);
                fieldCustomerName=app.LangManager.getText('field.order.CustomerName');
                fieldCustomerName=string(fieldCustomerName);

                sqlInsert = sprintf(...
                    'INSERT INTO '+tableName+' ('+fieldOrderNo+', '+fieldCustomerName+', '+fieldQuantity+', '+fieldProductName+', '+fieldDeliveryDate+') VALUES (''%s'', ''%s'', %d, ''%s'', #%s#)', ...
                    strrep(orderID, '''', ''''''), ...
                    strrep(customerName, '''', ''''''), ...
                    quantity, ...
                    strrep(productName, '''', ''''''), ...
                    deliveryDate);


                % 5. Execute SQL insertion and commit transaction
                curs = exec(conn, sqlInsert);  % execute SQL
                fetch(curs);                   % confirm execution result  
                commit(conn);                  % commit transaction
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                refreshOrderTable(app); % call the refresh function
                % 6. give the message
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, 'Order has been successfully inserted into the database！', dialogOperationCompleted, 'Icon', 'success');
                % clear input
                app.edtOrderID.Value = '';
                app.edtCustomer.Value = '';
                app.edtQuantity.Value = 1;     % Restore default quantity to 1
                app.edtProduct.Value = '';
                app.dpDeliveryDate.Value = datetime('today');  % Restore default date to today


                % 7.Handle database exceptions.
            catch err
            
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                dialogInsertFailed=app.LangManager.getText('dialog.insertFailed');
                dialogInsertFailed=string(dialogInsertFailed);
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, [dialogInsertFailed err.message], dialogError, 'Icon', 'error');
            end


        end

        % Button pushed function: deleteOrderButton
        function deleteOrderButtonPushed(app, event)
            % get Selection
            selection = app.UITable_order.Selection;

            % check if there is a selected row
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            
            if isstruct(selection)                
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)                
                selectedRowIdx = selection;
             
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end
          
            try
                %order ID
                selectedOrderID = app.UITable_order.Data{selectedRowIdx, 1};
                selectedOrderID = string(selectedOrderID);
            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['Failed to retrieve order number：' err.message], dialogError, 'Icon', 'error');
                return;
            end

         
            confirmResult = uiconfirm(app.SIS_APS, ...
                "Are you sure you want to delete the record with Order ID「" + selectedOrderID + "」？", ...
                'Confirm to delete', ...
                'Options', {'Confirm','Cancel'}, ...
                'DefaultOption', 2);
            if ~strcmp(confirmResult, 'Confirm')
                return;
            end

            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end

                % construct SQL
                tableName=app.LangManager.getText('table.order.name');
                tableName=string(tableName);
                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);
                sqlDelete = sprintf(...
                    'DELETE FROM '+tableName+' WHERE '+fieldOrderNo+' = ''%s''', ...
                    strrep(char(selectedOrderID), '''', '''''') ...
                    );

                % execute sql
                curs = exec(conn, sqlDelete);
                fetch(curs);
                commit(conn);
               
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
                refreshOrderTable(app); %call the refresh function
                
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                dialogProcessDetailDeleted=app.LangManager.getText('dialog.processDetailDeleted');
                dialogProcessDetailDeleted=string(dialogProcessDetailDeleted);
                uialert(app.SIS_APS, dialogProcessDetailDeleted, dialogOperationCompleted, 'Icon', 'success');


            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                dialogDeleteFailed=app.LangManager.getText('dialog.deleteFailed');
                dialogDeleteFailed=string(dialogDeleteFailed);
                dialogDeleteExceptionDetail=app.LangManager.getText('dialog.deleteExceptionDetail');
                dialogDeleteExceptionDetail=string(dialogDeleteExceptionDetail);

                uialert(app.SIS_APS, [dialogDeleteFailed err.message], dialogError, 'Icon', 'error');
                disp([dialogDeleteExceptionDetail err.message]); % 
                finally
                
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
            end
        end

        % Button pushed function: modifyOrderButton
        function modifyOrderButtonPushed(app, event)
            % 1. Get the selected row in the table
            selection = app.UITable_order.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_order.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_order.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % 2.Get selected row data from the matrix
            orderData = app.UITable_order.Data;
            orderID = orderData(selectedRowIdx, 1);        % order no（key，read only）
            customerName = orderData(selectedRowIdx, 5);    % customer name

            quantity_raw = orderData(selectedRowIdx, 3);    
            % Force conversion to double-precision, default to 1 for non-numeric values
            if isnumeric(quantity_raw)
                quantity = double(quantity_raw); %
            else
                
                try
                    quantity = str2double(string(quantity_raw));
                catch
                    quantity = 1; 
                end
            end
            
            if ~isscalar(quantity) 
                if ~isempty(quantity)
                    quantity = quantity(1);
                else
                    quantity = 1;
                end
            end
           
            if isnan(quantity) || quantity < 1
                quantity = 1;
            end
            % ====================================================

            productName = orderData(selectedRowIdx, 2);     % product name
            deliveryDateStr = orderData(selectedRowIdx, 4); % delivery date

            
            try
                deliveryDate = datetime(deliveryDateStr, 'InputFormat', 'yyyy-MM-dd');
            catch
                deliveryDate = datetime('today'); % 
            end

            % Create pop-up edit window
            windowModifyOrder=app.LangManager.getText('window.modifyOrder');
            windowModifyOrder=string(windowModifyOrder);
            editFig = uifigure('Name', windowModifyOrder, 'Position', [200, 200, 400, 300]);
            editFig.Resize = 'off'; % Disable window resizing

            
            fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
            fieldOrderNo=string(fieldOrderNo);
            lblOrderID = uilabel(editFig, 'Text', fieldOrderNo, 'Position', [30, 260, 80, 22]);
            txtOrderID = uitextarea(editFig, 'Value', string(orderID), 'Position', [120, 260, 250, 22]);
            txtOrderID.Enable = 'off'; % 

            % 
            fieldCustomerName=app.LangManager.getText('field.order.CustomerName');
            fieldCustomerName=string(fieldCustomerName);
            lblCustomer = uilabel(editFig, 'Text', fieldCustomerName, 'Position', [30, 220, 80, 22]);
            txtCustomer = uitextarea(editFig, 'Value', string(customerName), 'Position', [120, 220, 250, 22]);

            % 
            fieldQuantity=app.LangManager.getText('field.order.Quantity');
            fieldQuantity=string(fieldQuantity);
            lblQuantity = uilabel(editFig, 'Text', fieldQuantity, 'Position', [30, 180, 80, 22]);
            numQuantity = uispinner(editFig, 'Value', quantity, 'Position', [120, 180, 250, 22]);
            %%numQuantity.Min = 1; % 

            % 
            fieldProductName=app.LangManager.getText('field.order.ProductName');
            fieldProductName=string(fieldProductName);
            lblProduct = uilabel(editFig, 'Text', fieldProductName, 'Position', [30, 140, 80, 22]);
            txtProduct = uitextarea(editFig, 'Value', string(productName), 'Position', [120, 140, 250, 22]);

            % 
            fieldDeliveryDate=app.LangManager.getText('field.order.DeliveryDate');
            fieldDeliveryDate=string(fieldDeliveryDate);
            lblDate = uilabel(editFig, 'Text', fieldDeliveryDate, 'Position', [30, 100, 80, 22]);
            dpDate = uidatepicker(editFig, 'Value', deliveryDate, 'Position', [120, 100, 250, 22]);

            % 
            buttonSave=app.LangManager.getText('button.save');
            buttonSave=string(buttonSave);
            buttonCancel=app.LangManager.getText('button.cancel');
            buttonCancel=string(buttonCancel);
            btnSave = uibutton(editFig, 'Text', buttonSave, 'Position', [100, 40, 90, 30]);
            btnCancel = uibutton(editFig, 'Text', buttonCancel, 'Position', [210, 40, 90, 30]);


            btnSave.ButtonPushedFcn = @(~,~) saveModifiedOrder(app, editFig, orderID, txtCustomer, numQuantity, txtProduct, dpDate);

           
            btnCancel.ButtonPushedFcn = @(~,~) delete(editFig);
        end

        % Button pushed function: updateCraftButton
        function updateCraftButtonPushed(app, event)
            % 1. Get the selected row in the table
            selection = app.UITable_craft.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_order.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_order.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            craftData = app.UITable_craft.Data;
            craftID = craftData(selectedRowIdx, 1);        
            craftNo = craftData(selectedRowIdx, 2);    

            
            craftDesc_raw = craftData(selectedRowIdx, 3);    

            % ====================================================
            craftVersion = craftData(selectedRowIdx, 4);     
            craftDateStr = craftData(selectedRowIdx, 5); 

            
            try
                deliveryDate = datetime(craftDateStr, 'InputFormat', 'yyyy-MM-dd');
            catch
                deliveryDate = datetime('today'); 
            end

            
            windowModifyCraft=app.LangManager.getText('window.modifyCraft');
            windowModifyCraft=string(windowModifyCraft);
            editFig = uifigure('Name', windowModifyCraft, 'Position', [200, 200, 400, 300]);
            editFig.Resize = 'off'; % 


            fieldProcessCode=app.LangManager.getText('field.ProcessMaster.ProcessCode');
            fieldProcessCode=string(fieldProcessCode);
            lblOrderID = uilabel(editFig, 'Text', fieldProcessCode, 'Position', [30, 260, 100, 22]);
            txtOrderID = uitextarea(editFig, 'Value', string(craftNo), 'Position', [120, 260, 250, 22]);
            txtOrderID.Enable = 'off'; % 

            
            fieldProcessDesc=app.LangManager.getText('field.ProcessMaster.ProcessDesc');
            fieldProcessDesc=string(fieldProcessDesc);
            lblCraftDesc= uilabel(editFig, 'Text', fieldProcessDesc, 'Position', [30, 220, 100, 22]);
            txtCraftDesc = uitextarea(editFig, 'Value', string(craftDesc_raw), 'Position', [120, 220, 250, 22]);
            
            fieldVersion=app.LangManager.getText('field.ProcessMaster.Version');
            fieldVersion=string(fieldVersion);
            lblCraftVersion = uilabel(editFig, 'Text', fieldVersion, 'Position', [30, 140, 80, 22]);
            txtCraftVersion = uitextarea(editFig, 'Value', string(craftVersion), 'Position', [120, 140, 250, 22]);
            buttonSave=app.LangManager.getText('button.save');
            buttonSave=string(buttonSave);
            buttonCancel=app.LangManager.getText('button.cancel');
            buttonCancel=string(buttonCancel);
            btnSave = uibutton(editFig, 'Text', buttonSave, 'Position', [100, 40, 90, 30]);
            btnCancel = uibutton(editFig, 'Text', buttonCancel, 'Position', [210, 40, 90, 30]);
            btnSave.ButtonPushedFcn = @(~,~) saveModifiedCraft(app, editFig, craftID, txtCraftDesc, txtCraftVersion);
            btnCancel.ButtonPushedFcn = @(~,~) delete(editFig);
        end

        % Cell selection callback: UITable_craft
        function UITable_craftCellSelection(app, event)
            doLoadingDataForCraftProcess(app);
            % doLoadingDataForCraftDet(app,event);
            doLoadingDataForCraftDet(app);

        end

        % Cell selection callback: UITableCraftDet
        function UITableCraftDetCellSelection(app, event)
            doAddingDataForCraftEq(app);
            
            selectedIndices = event.Indices;
            if isempty(selectedIndices)
                return; 
            end
            selectedRow = selectedIndices(1); % 

            
            craftDetData = app.UITableCraftDet.Data; % 
            selectedProcessID = craftDetData{selectedRow, 1}; % 

            % 3. Query the detail data corresponding to this process
           
            tableName=app.LangManager.getText('table.processDetailEquipment.name');
            tableName=string(tableName);
            fieldEquipmentName=app.LangManager.getText('field.PDE.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);
            fieldID=app.LangManager.getText('field.PDE.ID');
            fieldID=string(fieldID);
            fieldProcessDetailID=app.LangManager.getText('field.PDE.ProcessDetailID');
            fieldProcessDetailID=string(fieldProcessDetailID);
            fieldProcessTime=app.LangManager.getText('field.PDE.ProcessTime');
            fieldProcessTime=string(fieldProcessTime);
            query = sprintf("SELECT "+fieldID+","+fieldEquipmentName+","+fieldProcessTime+" FROM "+tableName+" WHERE "+fieldProcessDetailID+" = %d", selectedProcessID);

            dbConn= app.getConn();
            detailData = fetch(dbConn, query); % 
            app.UITableCraftEq.ColumnName={fieldID,fieldEquipmentName,fieldProcessTime};
            % 4. Update the process details list
            app.UITableCraftEq.Data = detailData;
            % app.UITableCraftEq.Data = [];
        end

        % Button pushed function: Button_addCraftEq
        function Button_addCraftEqPushed(app, event)
            doAddingEqToCraft(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.SIS_APS);
            close all; %
        end

        % Button pushed function: Button_addCraftDet
        function Button_addCraftDetPushed(app, event)
            doAddingProcessToCraftDet(app);
        end

        % Button pushed function: Button_addEqData
        function Button_addEqDataPushed(app, event)
          
           
            eqName = app.EditField_equipName.Value;          
            eqNo = app.EditField_equipNo.Value;    % 
            eqProvider= app.EditField_equipCompany.Value;       % 
            eqMemo = app.TextArea_equipMemo.Value;      % 
            eqMemo=cell2mat(eqMemo);
           
            %deliveryDate = datestr(app.dpDeliveryDate.Value, 'yyyy-mm-dd');
            buyDatetime = app.DatePicker_buyTime.Value; % 
            buyDatetime = char(buyDatetime, 'yyyy-MM-dd'); % 

           
            if isempty(eqName) || isempty(eqNo)
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'equipment name、equipment code cannot be empty！', dialogError, 'Icon', 'error');
                return;
            end


            try
                
                conn=app.getConn();
             
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end
             
               
                tableName=app.LangManager.getText('table.equipment.name');
                tableName=string(tableName);
                fieldEquipmentCode=app.LangManager.getText('field.Equipment.EquipmentCode');
                fieldEquipmentCode=string(fieldEquipmentCode);
                fieldPurchaseDate=app.LangManager.getText('field.Equipment.PurchaseDate');
                fieldPurchaseDate=string(fieldPurchaseDate);
                fieldEquipmentName=app.LangManager.getText('field.Equipment.EquipmentName');
                fieldEquipmentName=string(fieldEquipmentName);
                fieldSupplier=app.LangManager.getText('field.Equipment.Supplier');
                fieldSupplier=string(fieldSupplier);
                fieldRemarks=app.LangManager.getText('field.Equipment.Remarks');
                fieldRemarks=string(fieldRemarks);
                sqlInsert = sprintf(...
                    'INSERT INTO '+tableName+' ('+fieldEquipmentCode+', '+fieldEquipmentName+', '+fieldSupplier+','+fieldPurchaseDate+','+fieldRemarks+') VALUES (''%s'', ''%s'', ''%s'', #%s#, ''%s'')', ...
                    strrep(eqNo, '''', ''''''), ...
                    strrep(eqName, '''', ''''''), ...
                    strrep(eqProvider, '''', ''''''), ...
                    buyDatetime,...
                    strrep(eqMemo, '''', ''''''));
                % 
                curs = exec(conn, sqlInsert);  % 
                fetch(curs);                   % 
                commit(conn);                  % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                refreshEquipmentTable(app); % 
                % 
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, 'Equipment data has been successfully inserted into the database！', dialogOperationCompleted, 'Icon', 'success');
                % 
                app.EditField_equipName.Value = '';
                app.EditField_equipNo.Value = '';
                app.EditField_equipCompany.Value = '';
                app.DatePicker_buyTime.Value = datetime('today');  % 
                
            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                % 
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to add：' err.message], dialogError, 'Icon', 'error');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            orderTitle=app.LangManager.getText("tab.orderManagement");
            craftTitle=app.LangManager.getText("tab.craftManagement");
            equipTitle=app.LangManager.getText("tab.equipmentManagement");
            jobplanTitle=app.LangManager.getText("tab.jobPlan");
            jobSchedulingTitle=app.LangManager.getText("tab.jobScheduling");
           
            switch selectedTab.Title
                case orderTitle%%'order management'
                    refreshOrderTable(app);
                    doLoadingDataForCraftNo(app);
                case craftTitle
                    refreshCraftTable(app);
                case equipTitle
                    refreshEquipmentTable(app);
                case jobplanTitle
                case jobSchedulingTitle

            end
        end

        % Button pushed function: Button_deleteEquipmentData
        function Button_deleteEquipmentDataPushed(app, event)
            % 
            selection = app.UITable_equipment.Selection;

            % 
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % 
            if isstruct(selection)
                % 
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                selectedRowIdx = selection;
                % 
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end
            % 
            try
                
                selectedEquipNo = app.UITable_equipment.Data{selectedRowIdx, 1};
                selectedEquipNo = string(selectedEquipNo);
            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to get equipment code：' err.message], dialogError, 'Icon', 'error');
                return;
            end

            % 
            confirmResult = uiconfirm(app.SIS_APS, ...
                "Are you sure you want to delete the record with Order ID「" + selectedEquipNo + "」？", ...
                'Confirm to delete', ...
                'Options', {'Confirm','Cancel'}, ...
                'DefaultOption', 2);
            if ~strcmp(confirmResult, 'Confirm')
                return;
            end

            % 
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end

               
                tableName=app.LangManager.getText('table.equipment.name');
                tableName=string(tableName);
                fieldEquipmentCode=app.LangManager.getText('field.Equipment.EquipmentCode');
                fieldEquipmentCode=string(fieldEquipmentCode);

                sqlDelete = sprintf(...
                    'DELETE FROM '+tableName+' WHERE '+fieldEquipmentCode+' = ''%s''', ...
                    strrep(char(selectedEquipNo), '''', '''''') ...
                    );

                
                curs = exec(conn, sqlDelete);
                fetch(curs);
                commit(conn);
               
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
                refreshEquipmentTable(app); 
                
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                dialogProcessDetailDeleted=app.LangManager.getText('dialog.processDetailDeleted');
                dialogProcessDetailDeleted=string(dialogProcessDetailDeleted);
                uialert(app.SIS_APS, dialogProcessDetailDeleted, dialogOperationCompleted, 'Icon', 'success');


            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to delete：' err.message], dialogError, 'Icon', 'error');
                disp(['delete exception：' err.message]); % 
               
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
            end
        end

        % Button pushed function: Button_delCraftDet
        function Button_delCraftDetPushed(app, event)
          
            selection = app.UITableCraftDet.Selection;
            dialogSelectDelProDetailTip=app.LangManager.getText('dialog.selectDelProDetailTip');
            dialogSelectDelProDetailTip=string(dialogSelectDelProDetailTip);
            dialogOperationTip=app.LangManager.getText('dialog.operationTip');
            dialogOperationTip=string(dialogOperationTip);

            if isempty(selection)
                uialert(app.SIS_APS, dialogSelectDelProDetailTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

           
            if isstruct(selection)
               
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                selectedRowIdx = selection;
               
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end
           
            try
              
                selectedCraftDetID = app.UITableCraftDet.Data{selectedRowIdx, 1};

            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to get craft detail ID：' err.message], dialogError, 'Icon', 'error');
                return;
            end

            
            dialogConfirmToDelProDetail=app.LangManager.getText('dialog.confirmToDelProDetail');
            dialogConfirmToDelProDetail=string(dialogConfirmToDelProDetail);
            dialogConfirmToDel=app.LangManager.getText('dialog.confirmToDel');
            dialogConfirmToDel=string(dialogConfirmToDel);
            dialogConfirm=app.LangManager.getText('dialog.confirm');
            % dialogConfirm=string(dialogConfirm);
            dialogCancel=app.LangManager.getText('dialog.cancel');
            % dialogCancel=string(dialogCancel);
            dialogConfirmToDelProDetailStr=dialogConfirmToDelProDetail+"「" + selectedCraftDetID + "」？";
            confirmResult = uiconfirm(app.SIS_APS, ...
                dialogConfirmToDelProDetailStr, ...
                dialogConfirmToDel, ...
                'Options', {dialogConfirm,dialogCancel}, ...
                'DefaultOption', 2);
            if ~strcmp(confirmResult, dialogConfirm)
                return;
            end

           
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end

               
                tableName=app.LangManager.getText('table.processRouteDetail.name');
                tableName=string(tableName);
                fieldID=app.LangManager.getText('field.PRD.ID');
                fieldID=string(fieldID);
                sqlDelete = sprintf(...
                    'DELETE FROM '+tableName+' WHERE '+fieldID+' = %d', ...
                    selectedCraftDetID ...
                    );

               
                curs = exec(conn, sqlDelete);
                fetch(curs);
                commit(conn);
               
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
                refreshEquipmentTable(app); %
               
                dialogProcessDetailDeleted=app.LangManager.getText('dialog.processDetailDeleted');
                dialogProcessDetailDeleted=string(dialogProcessDetailDeleted);
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                uialert(app.SIS_APS, dialogProcessDetailDeleted, dialogOperationCompleted, 'Icon', 'success');
               
                doLoadingDataForCraftDet(app);

            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to delte：' err.message], dialogError, 'Icon', 'error');
                disp(['delete exception：' err.message]); % 
               
               
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
            end
        end

        % Button pushed function: Button_delCraftEq
        function Button_delCraftEqPushed(app, event)
            
            selection = app.UITableCraftEq.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            
            if isstruct(selection)
                
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                selectedRowIdx = selection;
              
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end
            
            try
                
                selectedCraftDetEQID = app.UITableCraftEq.Data{selectedRowIdx, 1};

            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to get craft detail equipment：' err.message],dialogError, 'Icon', 'error');
                return;
            end

         
            confirmResult = uiconfirm(app.SIS_APS, ...
                "Confirm deletion of record with ID「" + selectedCraftDetEQID + "」？", ...
                'Confirm to delete', ...
                'Options', {'Confirm','Cancel'}, ...
                'DefaultOption', 2);
            if ~strcmp(confirmResult, 'Confirm')
                return;
            end

        
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end

                % 
                tableName=app.LangManager.getText('table.processDetailEquipment.name');
                tableName=string(tableName);
                fieldID=app.LangManager.getText('field.PDE.ID');
                fieldID=string(fieldID);
                sqlDelete = sprintf(...
                    'DELETE FROM '+tableName+' WHERE '+fieldID+' = %d', ...
                    selectedCraftDetEQID ...
                    );

                % 
                curs = exec(conn, sqlDelete);
                fetch(curs);
                commit(conn);
                % 
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
                
                % 
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);

                dialogProcessDetailDeleted=app.LangManager.getText('dialog.processDetailDeleted');
                dialogProcessDetailDeleted=string(dialogProcessDetailDeleted);
                uialert(app.SIS_APS, dialogProcessDetailDeleted, dialogOperationCompleted, 'Icon', 'success');
                %%Reload process detail equipment data
                doLoadingDataForCraftDetEq(app);
            end
        end

        % Button pushed function: Button_updateEquipmentData
        function Button_updateEquipmentDataPushed(app, event)
            % 1. Get the selected row in the table
            selection = app.UITable_equipment.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row
            selectedRowIdx = [];
            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_order.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_order.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            eqData = app.UITable_equipment.Data;
            eqNo = eqData(selectedRowIdx, 1);        % 
            eqName = eqData(selectedRowIdx, 2);    % 

            
            eqProvider = eqData(selectedRowIdx, 3);    %

            % ====================================================
            eqMemo= eqData(selectedRowIdx, 5);     % 
            buyDateStr = eqData(selectedRowIdx, 4); % 

            %
            try
                buyDate = datetime(buyDateStr, 'InputFormat', 'yyyy-MM-dd');
            catch
                buyDate = datetime('today'); % 
            end

            % 
            windowModifyEquipment=app.LangManager.getText('window.modifyEquipment');
            windowModifyEquipment=string(windowModifyEquipment);
            editFig = uifigure('Name', windowModifyEquipment, 'Position', [200, 200, 400, 300]);
            editFig.Resize = 'off'; % 


            fieldEquipmentCode=app.LangManager.getText('field.Equipment.EquipmentCode');
            fieldEquipmentCode=string(fieldEquipmentCode);
            lblEqNo = uilabel(editFig, 'Text',fieldEquipmentCode, 'Position', [30, 260, 80, 22]);
            txtEqNo = uitextarea(editFig, 'Value', string(eqNo), 'Position', [120, 260, 250, 22]);
            txtEqNo.Enable = 'off'; % 

            % 
            fieldEquipmentName=app.LangManager.getText('field.Equipment.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);
            lblEqName = uilabel(editFig, 'Text', fieldEquipmentName, 'Position', [30, 220, 80, 22]);
            txtEqName = uitextarea(editFig, 'Value', string(eqName), 'Position', [120, 220, 250, 22]);

            % 
            fieldPurchaseDate=app.LangManager.getText('field.Equipment.PurchaseDate');
            fieldPurchaseDate=string(fieldPurchaseDate);
            lblBuyDate = uilabel(editFig, 'Text', fieldPurchaseDate, 'Position', [30, 100, 80, 22]);
            dpBuyDate = uidatepicker(editFig, 'Value', buyDate, 'Position', [120, 100, 250, 22]);

            % 
            fieldRemarks=app.LangManager.getText('field.Equipment.Remarks');
            fieldRemarks=string(fieldRemarks);
            lblMemo = uilabel(editFig, 'Text', fieldRemarks, 'Position', [30, 140, 80, 22]);
            txtMemo = uitextarea(editFig, 'Value', string(eqMemo), 'Position', [120, 140, 250, 22]);


            % 
            buttonSave=app.LangManager.getText('button.save');
            buttonSave=string(buttonSave);
            buttonCancel=app.LangManager.getText('button.cancel');
            buttonCancel=string(buttonCancel);
            btnSave = uibutton(editFig, 'Text', buttonSave, 'Position', [100, 40, 90, 30]);
            btnCancel = uibutton(editFig, 'Text', buttonCancel, 'Position', [210, 40, 90, 30]);


            btnSave.ButtonPushedFcn = @(~,~) saveModifiedEqData(app, editFig, txtEqNo, txtEqName, txtMemo, dpBuyDate);

         
            btnCancel.ButtonPushedFcn = @(~,~) delete(editFig);
        end

        % Button pushed function: Button_setCraftNo
        function Button_setCraftNoPushed(app, event)
            % 1. Get the selected row in the table
            selection = app.UITable_order.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row

            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_order.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_craft.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize the selection state in the production order list！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            orderData = app.UITable_order.Data;
            orderNo = orderData(selectedRowIdx, 1); 
            orderNo=cell2mat(orderNo);
            conn = [];
            curs = [];
            try
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end
                craftNo=app.DropDown_craftNo.Value;

                currentTime = datetime('now');
                % 

                updateDate = char(currentTime, 'yyyy-MM-dd HH:mm:ss'); % 

            
                tableName=app.LangManager.getText('table.order.name');
                tableName=string(tableName);
                ProcessCode=app.LangManager.getText('field.order.ProcessCode');
                ProcessCode=string(ProcessCode);
                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);
                fieldModifyTime=app.LangManager.getText('field.order.ModifyTime');
                fieldModifyTime=string(fieldModifyTime);
                sqlInsert = sprintf(...
                    'update '+tableName+' set '+ProcessCode+'=''%s'','+fieldModifyTime+'=#%s# where '+fieldOrderNo+'=''%s'' ', ...
                    ...
                    strrep(craftNo, '''', ''''''), ...
                    updateDate,...
                    strrep(orderNo, '''', ''''''));

                % 
                curs = exec(conn, sqlInsert);
                fetch(curs);
                commit(conn);

                
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                dialogOrderCraftSettingSuccess=app.LangManager.getText('dialog.orderCraftSettingSuccess');
                dialogOrderCraftSettingSuccess=string(dialogOrderCraftSettingSuccess);

                uialert(app.SIS_APS, dialogOrderCraftSettingSuccess, dialogOperationCompleted, 'Icon', 'success');
                % delete(editFig); % 


                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                
                refreshOrderTable(app);

            catch err
                
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

                
                dialogOrderCraftSettingFailure=app.LangManager.getText('dialog.orderCraftSettingFailure');
                dialogOrderCraftSettingFailure=string(dialogOrderCraftSettingFailure);
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);

                dialog.error
                uialert(app.SIS_APS, [dialogOrderCraftSettingFailure err.message], dialogError, 'Icon', 'error');
                disp(['exception：' err.message]);
            end
        end

        % Button pushed function: Button_setSchedulingPeriod
        function Button_setSchedulingPeriodPushed(app, event)
            %  Get the selected row in the table
            selection = app.UITable_order.Selection;
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % Extract the index of the selected row

            if isstruct(selection)
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                if strcmp(app.UITable_order.SelectionType, 'cell')
                    [selectedRowIdx, ~] = ind2sub(size(app.UITable_craft.Data), selection);
                else
                    selectedRowIdx = selection;
                end
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize the selection state in the production order list！', dialogError, 'Icon', 'error');
                return;
            end

            % 2. Get selected row data from the matrix
            orderData = app.UITable_order.Data;
            orderNo = orderData(selectedRowIdx, 1); %%
            orderNo=cell2mat(orderNo);
            conn = [];
            curs = [];
            try
                conn = app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    if ~isempty(conn) && isopen(conn)
                        close(conn);
                    end
                    return;
                end
                schedulingPeriod=app.EditField_schedulingPeriod.Value;
                if isempty(schedulingPeriod)
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, 'input the scheduling period！', dialogError, 'Icon', 'error');
                    return;
                end
                currentTime = datetime('now');
                % 

                updateDate = char(currentTime, 'yyyy-MM-dd HH:mm:ss'); % 

                % 
                tableName=app.LangManager.getText('table.order.name');
                tableName=string(tableName);
                fieldOrderNo=app.LangManager.getText('field.order.OrderNo');
                fieldOrderNo=string(fieldOrderNo);
                SchedulingCycle=app.LangManager.getText('field.order.SchedulingCycle');
                SchedulingCycle=string(SchedulingCycle);
                fieldModifyTime=app.LangManager.getText('field.order.ModifyTime');
                fieldModifyTime=string(fieldModifyTime);

                sqlInsert = sprintf(...
                    'update '+tableName+' set '+SchedulingCycle+'=''%s'','+fieldModifyTime+'=#%s# where '+fieldOrderNo+'=''%s'' ', ...
                    ...
                    strrep(schedulingPeriod, '''', ''''''), ...
                    updateDate,...
                    strrep(orderNo, '''', ''''''));


                curs = exec(conn, sqlInsert);
                fetch(curs);
                commit(conn);

                % refreshCraftTable(app); 
                % 
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                dialogSettingSuccess=app.LangManager.getText('dialog.settingSuccess');
                dialogSettingSuccess=string(dialogSettingSuccess);
                uialert(app.SIS_APS, dialogSettingSuccess, dialogOperationCompleted, 'Icon', 'success');
                % delete(editFig); % 


                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end
                %%
                refreshOrderTable(app);

            catch err
                % 
                if ~isempty(curs)
                    close(curs);
                end
                if ~isempty(conn) && isopen(conn)
                    close(conn);
                end

                % 
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['Failed to set the production scheduling cycle：' err.message], dialogError, 'Icon', 'error');
                disp(['Error Details：' err.message]);
            end
        end

        % Button pushed function: Button_scheduling
        function Button_schedulingPushed(app, event)
            %%
            schedulingPeriod=app.TextArea_5.Value;%%
            schedulingPeriod=strtrim(schedulingPeriod);

            if(schedulingPeriod=="")
                msgbox("input scheduling period");
                return;
            end
            tableName=app.LangManager.getText('table.jobPlan.name');
            tableName=string(tableName);
            fieldOrderNo=app.LangManager.getText('field.OperationPlan.OrderNo');
            fieldOrderNo=string(fieldOrderNo);


            fieldProcessSeq=app.LangManager.getText('field.OperationPlan.ProcessSeq');
            fieldProcessSeq=string(fieldProcessSeq);
            fieldProcessTimeSet=app.LangManager.getText('field.OperationPlan.ProcessTimeSet');
            fieldProcessTimeSet=string(fieldProcessTimeSet);

            fieldEquipmentIDSet=app.LangManager.getText('field.OperationPlan.EquipmentIDSet');
            fieldEquipmentIDSet=string(fieldEquipmentIDSet);

            orderTableName=app.LangManager.getText('table.order.name');
            orderTableName=string(orderTableName);
            fieldSchedulingCycle=app.LangManager.getText('field.order.SchedulingCycle');
            fieldSchedulingCycle=string(fieldSchedulingCycle);
            fieldOrderOrderNo=app.LangManager.getText('field.order.OrderNo');
            fieldOrderOrderNo=string(fieldOrderOrderNo);

            sql="select "+fieldOrderNo+" as slat_L3,"+fieldProcessSeq+" as slat_proseq,"+fieldEquipmentIDSet+" as slat_eqNameArr,"+fieldProcessTimeSet+" as slat_eqTimeArr from "+tableName;
            sql=sql+" where "+fieldOrderNo+"  in(SELECT "+fieldOrderOrderNo+" from "+orderTableName+" where "+fieldSchedulingCycle+" = '"+schedulingPeriod+"')";
            sql=sql+"  order by "+fieldOrderNo+","+fieldProcessSeq;%%
            conn=app.getConn();
            cursor=exec(conn,sql);%%SQL

            cursorData=fetch(cursor);
            jobData=cursorData.Data;
            [n,m]=size(jobData);
            strN=" n="+ string(n);
            strM=" m="+ string(m);
            htSize=java.util.Hashtable;%
            L3Info={};%%
            for i=1:n
                slatL3No=cell2str(app,jobData(i,1));
                if(htSize.containsKey(slatL3No))
                    iSize=htSize.get(slatL3No);
                    iSize=iSize+1;
                    htSize.put(slatL3No,iSize);
                else
                    htSize.put(slatL3No,1);
                    L3Info=[L3Info;slatL3No];
                end
            end
            %%

            tableName=app.LangManager.getText('table.equipment.name');
            tableName=string(tableName);
            fieldID=app.LangManager.getText('field.Equipment.ID');
            fieldID=string(fieldID);
            fieldEquipmentName=app.LangManager.getText('field.Equipment.EquipmentName');
            fieldEquipmentName=string(fieldEquipmentName);
            fieldProductionWorkshop=app.LangManager.getText('field.Equipment.ProductionWorkshop');
            fieldProductionWorkshop=string(fieldProductionWorkshop);
            slatScene="FJSP1";
            strWhere= " where "+fieldProductionWorkshop+"='"+slatScene+"'";
            sql="select "+fieldID+" from "+tableName;
            sql=sql+strWhere;
            sql=sql+" order by "+fieldID;
            sqlName="select "+fieldEquipmentName+" from "+tableName;
            sqlName=sqlName+strWhere;
            sqlName=sqlName+" order by "+fieldID;
            eqData = doReadingProcessTableDataBySql(app,sql);
            eqData=app.cell2str(eqData);
            eqDataArr=double(eqData);
            eqDataName = doReadingProcessTableDataBySql(app,sqlName);
            eqDataName=app.cell2str(eqDataName);
            eqDataName=string(eqDataName);
            [machineNum,~]=size(eqData);
            jobNum=htSize.size();
            jobInfo=cell(1,jobNum);
            operaNumVec=[];
            countStart=1;%%
            for iL3=1:jobNum
                slatL3NoKey=L3Info(iL3);
                operaNum= htSize.get(slatL3NoKey);%
                operaNumVec=[operaNumVec operaNum];
                countEnd=countStart+operaNum-1;
                curJobData=jobData(countStart:countEnd,:);
                tmpCraftInfo={};%%save job detail for current order
                for i=1:operaNum
                    slatSeq=double(cell2str(app,curJobData(i,1)));
                    strSlatEq=cell2str(app,curJobData(i,3));
                    strSlatTime=cell2str(app,curJobData(i,4));
                    if(contains(strSlatEq,','))
                        machineArr=split(strSlatEq,",");%%
                    else
                        machineArr=[double(strSlatEq)];
                    end
                    if(contains(strSlatTime,','))
                        timeArr=split(strSlatTime,",");%%
                    else
                        timeArr=[double(strSlatTime)];
                    end
                    operaInfo=size(machineArr);
                    machineArr=double(machineArr);
                    timeArr=double(timeArr);
                    processVec=ones(1,machineNum)*Inf;
                    for k=1:operaInfo
                        machine=machineArr(k);
                        processTime=timeArr(k);
                        indEq=find(eqDataArr==machine);
                        processVec(indEq)=processTime;
                    end
                    % processVec = processVec(isfinite(processVec));%%20260119add
                    tmpCraftInfo{i}=processVec;
                    % jobInfo{1,i}=[jobInfo{1,i};processVec];

                end
                jobInfo{iL3}=tmpCraftInfo;
                %                         TimeInfo{iL3}=[TimeInfo{iL3};slatTime];
                %                         EqInfo{iL3}=[EqInfo{iL3};slatEq];
                countStart=countStart+operaNum;
            end
            %% 
            candidateMachine=[];
            for i=1:length(jobInfo)
                [~,numberOfCraftDet]=size(jobInfo{i});
                tmpCraftDet=jobInfo{i};
                for j=1:numberOfCraftDet
                    tmpJobInfo=tmpCraftDet{j};
                    candidateMachine{i,j}=find(tmpJobInfo<Inf);
                end
            end
            runTimes=app.EditField_maxRun.Value;%%
            popSize=app.EditField_popSize.Value;
            stateSchedulingRunStateBegin=app.LangManager.getText('state.schedulingRunStateBegin');
            stateSchedulingRunStateBegin=string(stateSchedulingRunStateBegin);
            doLogging(app,stateSchedulingRunStateBegin);
            runTimes=double(runTimes);
            problemStruct={};
            problemStruct.popSize=popSize;
            problemStruct.Run=runTimes;
            problemStruct.iEQs=machineNum;
            problemStruct.iJobs=jobNum;
            problemStruct.jobInfo=jobInfo;
            problemStruct.operaNumVec=operaNumVec;
            problemStruct.candidateMachine=candidateMachine;
            [GlobalMins1,GlobalSolution,tim] = doInvkingFJSPSchedulingEngine(app,problemStruct);
            bestArr=GlobalMins1(:,end);
            [minVal,minInd]=min(bestArr);
            bestSolution=GlobalSolution(minInd,:);
            app.EditField_bestObj.Value=mat2str(minVal);
            app.EditField_optimizedSof.Value=  mat2str(bestSolution);%%record the best solution
            doUpdatingJobPlanByOptimizationResult(app,problemStruct,L3Info,htSize,eqDataName,bestSolution);
            stateSchedulingRunStateEnd=app.LangManager.getText('state.schedulingRunStateEnd');
            stateSchedulingRunStateEnd=string(stateSchedulingRunStateEnd);

            doLogging(app,stateSchedulingRunStateEnd);
        end

        % Selection changed function: UITable_order
        function UITable_orderSelectionChanged(app, event)
            selection = app.UITable_order.Selection;

        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            %
            selection = app.UITable_jobPlan.Selection;

            %
            if isempty(selection)
                dialogSelectSRecordTip=app.LangManager.getText('dialog.selectRecordTip');
                dialogSelectSRecordTip=string(dialogSelectSRecordTip);
                dialogOperationTip=app.LangManager.getText('dialog.operationTip');
                dialogOperationTip=string(dialogOperationTip);
                uialert(app.SIS_APS, dialogSelectSRecordTip, dialogOperationTip, 'Icon', 'info');
                return;
            end

            % 
            if isstruct(selection)
                % 
                selectedRowIdx = selection(1).Row;
            elseif isnumeric(selection)
                selectedRowIdx = selection;
                % 
            else
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, 'Unable to recognize table selection state！', dialogError, 'Icon', 'error');
                return;
            end
            % 
            try
                %
                selectedJobPlanNo = app.UITable_jobPlan.Data{selectedRowIdx, 1};
                selectedJobPlanNo = string(selectedJobPlanNo);
            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to get job plan：' err.message], dialogError, 'Icon', 'error');
                return;
            end

            % 
            confirmResult = uiconfirm(app.SIS_APS, ...
                "Confirm deletion of record with ID「" + selectedJobPlanNo + "」的记录吗？", ...
                'Confirm to delete', ...
                'Options', {'Confirm','Cancel'}, ...
                'DefaultOption', 2);
            if ~strcmp(confirmResult, 'Confirm')
                return;
            end

            % 
            conn = [];
            curs = [];
            try
                conn=app.getConn();
                if ~isopen(conn)
                    dialogFailureConnectDB=app.LangManager.getText('dialog.FailureConnectDB');
                    dialogFailureConnectDB=string(dialogFailureConnectDB);
                    dialogError=app.LangManager.getText('dialog.error');
                    dialogError=string(dialogError);
                    uialert(app.SIS_APS, dialogFailureConnectDB, dialogError, 'Icon', 'error');
                    return;
                end

                % 
                tableName=app.LangManager.getText('table.jobPlan.name');
                tableName=string(tableName);
                fieldOperationPlanID=app.LangManager.getText('field.OperationPlan.ID');
                fieldOperationPlanID=string(fieldOperationPlanID);
                sqlDelete='DELETE FROM '+tableName+' WHERE '+fieldOperationPlanID+' = '+selectedJobPlanNo;
                
                curs = exec(conn, sqlDelete);
                fetch(curs);
                commit(conn);
                % 
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
                refreshJobPlanTable(app); % 
                % 
                dialogOperationCompleted=app.LangManager.getText('dialog.operationCompleted');
                dialogOperationCompleted=string(dialogOperationCompleted);
                dialogProcessDetailDeleted=app.LangManager.getText('dialog.processDetailDeleted');
                dialogProcessDetailDeleted=string(dialogProcessDetailDeleted);
                uialert(app.SIS_APS, dialogProcessDetailDeleted, dialogOperationCompleted, 'Icon', 'success');


            catch err
                dialogError=app.LangManager.getText('dialog.error');
                dialogError=string(dialogError);
                uialert(app.SIS_APS, ['failure to delete：' err.message], dialogError, 'Icon', 'error');
                disp(['delete exception：' err.message]); % 
                % finally
                % 
                if exist('curs', 'var') && ~isempty(curs)
                    close(curs);
                end
                if exist('conn', 'var') && isopen(conn)
                    close(conn);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SIS_APS = uifigure('Visible', 'off');
            app.SIS_APS.Name = 'SISAPS';
            app.SIS_APS.Icon = fullfile(pathToMLAPP, 'main.png');
            app.systemMenu = uimenu(app.SIS_APS);
            app.systemMenu.Text = 'system';
            app.ExitMenu = uimenu(app.systemMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.Menu_help = uimenu(app.SIS_APS);
            app.Menu_help.Text = 'help';
            app.TabGroup = uitabgroup(app.SIS_APS);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.mainpageTab = uitab(app.TabGroup);
            app.mainpageTab.Title = 'main page';
            app.Image_navigator = uiimage(app.mainpageTab);
            app.Image_navigator.ImageSource = fullfile(pathToMLAPP, 'navigator_en.jpg');
            app.Image_flag = uiimage(app.mainpageTab);
            app.Image_flag.ImageSource = fullfile(pathToMLAPP, 'main.png');
            app.V10Label = uilabel(app.mainpageTab);
            app.V10Label.Text = 'V1.0';
            app.ordermanagementTab = uitab(app.TabGroup);
            app.ordermanagementTab.Title = 'order management';
            app.orderlistLabel = uilabel(app.ordermanagementTab);
            app.orderlistLabel.Text = 'order list';
            app.queryButton = uibutton(app.ordermanagementTab, 'push');
            app.queryButton.ButtonPushedFcn = createCallbackFcn(app, @queryButtonPushed, true);
            app.queryButton.Text = 'query';
            app.UITable_order = uitable(app.ordermanagementTab);
            app.UITable_order.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_order.RowName = {'Column 1'; 'Column 2'};
            app.UITable_order.SelectionType = 'row';
            app.UITable_order.ColumnEditable = [true true true true true true true false false false false false false false false false false false false false false false false false false];
            app.UITable_order.CellEditCallback = createCallbackFcn(app, @UITable_orderCellEdit, true);
            app.UITable_order.SelectionChangedFcn = createCallbackFcn(app, @UITable_orderSelectionChanged, true);
            app.UITable_order.Multiselect = 'off';
            app.Button_addOrder = uibutton(app.ordermanagementTab, 'push');
            app.Button_addOrder.ButtonPushedFcn = createCallbackFcn(app, @Button_addOrderPushed, true);
            app.Button_addOrder.Text = 'add';
            app.modifyOrderButton = uibutton(app.ordermanagementTab, 'push');
            app.modifyOrderButton.ButtonPushedFcn = createCallbackFcn(app, @modifyOrderButtonPushed, true);
            app.modifyOrderButton.Text = 'edit';
            app.deleteOrderButton = uibutton(app.ordermanagementTab, 'push');
            app.deleteOrderButton.ButtonPushedFcn = createCallbackFcn(app, @deleteOrderButtonPushed, true);
            app.deleteOrderButton.Text = 'delete';
            app.Label_57 = uilabel(app.ordermanagementTab);
            app.Label_57.Text = 'ONo:';
            app.ONoTextArea = uitextarea(app.ordermanagementTab);
            app.ONoTextArea.Tooltip = {'Order Number'};
            app.generateplanButton = uibutton(app.ordermanagementTab, 'push');
            app.generateplanButton.ButtonPushedFcn = createCallbackFcn(app, @generateplanButtonPushed, true);
            app.generateplanButton.Text = 'generate plan';
            app.Label_66 = uilabel(app.ordermanagementTab);
            app.Label_66.Text = 'ONo:';
            app.edtOrderID = uieditfield(app.ordermanagementTab, 'text');
            app.edtOrderID.Tooltip = {'Order Number'};
            app.Label_67 = uilabel(app.ordermanagementTab);
            app.Label_67.Text = 'PN:';
            app.edtProduct = uieditfield(app.ordermanagementTab, 'text');
            app.edtProduct.Tooltip = {'product name'};
            app.Label_68 = uilabel(app.ordermanagementTab);
            app.Label_68.Text = 'CN:';
            app.edtCustomer = uieditfield(app.ordermanagementTab, 'text');
            app.edtCustomer.Tooltip = {'customerName'};
            app.Label_69 = uilabel(app.ordermanagementTab);
            app.Label_69.Text = 'DD:';
            app.dpDeliveryDate = uidatepicker(app.ordermanagementTab);
            app.dpDeliveryDate.Tooltip = {'delivery date'};
            app.Label_70 = uilabel(app.ordermanagementTab);
            app.Label_70.Text = 'QTY：';
            app.edtQuantity = uieditfield(app.ordermanagementTab, 'numeric');
            app.edtQuantity.Value = 1;
            app.Label_87 = uilabel(app.ordermanagementTab);
            app.Label_87.Text = 'PNo:';
            app.DropDown_craftNo = uidropdown(app.ordermanagementTab);
            app.DropDown_craftNo.Tooltip = {'process Number'};
            app.Button_setCraftNo = uibutton(app.ordermanagementTab, 'push');
            app.Button_setCraftNo.ButtonPushedFcn = createCallbackFcn(app, @Button_setCraftNoPushed, true);
            app.Button_setCraftNo.Tooltip = {'set process no'};
            app.Button_setCraftNo.Text = 'set Pno';
            app.Label_88 = uilabel(app.ordermanagementTab);
            app.Label_88.Text = 'SC:';
            app.EditField_schedulingPeriod = uieditfield(app.ordermanagementTab, 'text');
            app.EditField_schedulingPeriod.Tooltip = {'production scheduling cycle'};
            app.Button_setSchedulingPeriod = uibutton(app.ordermanagementTab, 'push');
            app.Button_setSchedulingPeriod.ButtonPushedFcn = createCallbackFcn(app, @Button_setSchedulingPeriodPushed, true);
            app.Button_setSchedulingPeriod.Text = 'set SC';
            app.craftmanagementTab = uitab(app.TabGroup);
            app.craftmanagementTab.Title = 'craft management';
            app.craftlistLabel = uilabel(app.craftmanagementTab);
            app.craftlistLabel.Text = 'craft list';
            app.queryButton_2 = uibutton(app.craftmanagementTab, 'push');
            app.queryButton_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.queryButton_2.Text = 'query';
            app.addCraftButton = uibutton(app.craftmanagementTab, 'push');
            app.addCraftButton.ButtonPushedFcn = createCallbackFcn(app, @addCraftButtonPushed, true);
            app.addCraftButton.Text = 'add';
            app.deleteCraftButton = uibutton(app.craftmanagementTab, 'push');
            app.deleteCraftButton.Text = 'delete';
            app.updateCraftButton = uibutton(app.craftmanagementTab, 'push');
            app.updateCraftButton.ButtonPushedFcn = createCallbackFcn(app, @updateCraftButtonPushed, true);
            app.updateCraftButton.Text = 'edit';
            app.UITable_craft = uitable(app.craftmanagementTab);
            app.UITable_craft.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_craft.RowName = {'Column 1'; 'Column 2'};
            app.UITable_craft.SelectionType = 'row';
            app.UITable_craft.ColumnEditable = [true true true true true true true false false false false false false false false false false false false false false false false false false];
            app.UITable_craft.CellEditCallback = createCallbackFcn(app, @UITable_craftCellEdit, true);
            app.UITable_craft.CellSelectionCallback = createCallbackFcn(app, @UITable_craftCellSelection, true);
            app.UITable_craft.Multiselect = 'off';
            app.Label_56 = uilabel(app.craftmanagementTab);
            app.Label_56.Text = 'PNo:';
            app.PNoTextArea = uitextarea(app.craftmanagementTab);
            app.Label_71 = uilabel(app.craftmanagementTab);
            app.Label_71.Text = 'VN:';
            app.EditField_craftVersion = uieditfield(app.craftmanagementTab, 'text');
            app.Label_72 = uilabel(app.craftmanagementTab);
            app.Label_72.Text = 'PNo:';
            app.EditField_craftNo = uieditfield(app.craftmanagementTab, 'text');
            app.Label_73 = uilabel(app.craftmanagementTab);
            app.Label_73.Text = 'PTD:';
            app.EditField_craftDesc = uieditfield(app.craftmanagementTab, 'text');
            app.UITableCraftDet = uitable(app.craftmanagementTab);
            app.UITableCraftDet.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableCraftDet.RowName = {};
            app.UITableCraftDet.SelectionType = 'row';
            app.UITableCraftDet.CellSelectionCallback = createCallbackFcn(app, @UITableCraftDetCellSelection, true);
            app.UITableCraftEq = uitable(app.craftmanagementTab);
            app.UITableCraftEq.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableCraftEq.RowName = {};
            app.UITableCraftEq.SelectionType = 'row';
            app.Button_addCraftDet = uibutton(app.craftmanagementTab, 'push');
            app.Button_addCraftDet.ButtonPushedFcn = createCallbackFcn(app, @Button_addCraftDetPushed, true);
            app.Button_addCraftDet.Text = 'add';
            app.Button_delCraftDet = uibutton(app.craftmanagementTab, 'push');
            app.Button_delCraftDet.ButtonPushedFcn = createCallbackFcn(app, @Button_delCraftDetPushed, true);
            app.Button_delCraftDet.Text = 'delete';
            app.Button_addCraftEq = uibutton(app.craftmanagementTab, 'push');
            app.Button_addCraftEq.ButtonPushedFcn = createCallbackFcn(app, @Button_addCraftEqPushed, true);
            app.Button_addCraftEq.Text = 'add';
            app.Button_delCraftEq = uibutton(app.craftmanagementTab, 'push');
            app.Button_delCraftEq.ButtonPushedFcn = createCallbackFcn(app, @Button_delCraftEqPushed, true);
            app.Button_delCraftEq.Text = 'delete';
            app.craftdetailLabel = uilabel(app.craftmanagementTab);
            app.craftdetailLabel.Text = 'craft detail';
            app.Label_76 = uilabel(app.craftmanagementTab);
            app.Label_76.Text = 'EN:';
            app.DropDown_Eq = uidropdown(app.craftmanagementTab);
            app.DropDown_Eq.Items = {'data'};
            app.DropDown_Eq.Value = 'data';
            app.Label_77 = uilabel(app.craftmanagementTab);
            app.Label_77.Text = ' 工序名称：';
            app.DropDown_process = uidropdown(app.craftmanagementTab);
            app.DropDown_process.Items = {'data'};
            app.DropDown_process.Value = 'data';
            app.Label_78 = uilabel(app.craftmanagementTab);
            app.Label_78.Text = 'OT:';
            app.EditField_eqTime = uieditfield(app.craftmanagementTab, 'numeric');
            app.EditField_eqTime.Value = 1;
            app.Label_79 = uilabel(app.craftmanagementTab);
            app.Label_79.Text = '工序顺序：';
            app.EditField_processOrder = uieditfield(app.craftmanagementTab, 'numeric');
            app.equipmentlistLabel = uilabel(app.craftmanagementTab);
            app.equipmentlistLabel.Text = 'equipment list';
            app.eqIDLabel = uilabel(app.craftmanagementTab);
            app.eqIDLabel.Visible = 'off';
            app.eqIDLabel.Text = 'eqID：';
            app.DropDown_eqIDTmp = uidropdown(app.craftmanagementTab);
            app.DropDown_eqIDTmp.Visible = 'off';
            app.Label_97 = uilabel(app.craftmanagementTab);
            app.Label_97.Text = 'ST:';
            app.STDropDown = uidropdown(app.craftmanagementTab);
            app.STDropDown.Items = {'FJSP', 'PFSP', 'JSP', '其它'};
            app.STDropDown.Value = 'FJSP';
            app.equipmentmanagementTab = uitab(app.TabGroup);
            app.equipmentmanagementTab.Title = 'equipment management';
            app.queryButton_4 = uibutton(app.equipmentmanagementTab, 'push');
            app.queryButton_4.ButtonPushedFcn = createCallbackFcn(app, @queryButton_4Pushed, true);
            app.queryButton_4.Text = 'query';
            app.UITable_equipment = uitable(app.equipmentmanagementTab);
            app.UITable_equipment.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_equipment.RowName = {'Column 1'; 'Column 2'};
            app.UITable_equipment.SelectionType = 'row';
            app.UITable_equipment.ColumnEditable = [true true true true true true true false false false false false false false false false false false false false false false false false false];
            app.UITable_equipment.CellEditCallback = createCallbackFcn(app, @UITable_equipmentCellEdit, true);
            app.equipmentlistLabel_2 = uilabel(app.equipmentmanagementTab);
            app.equipmentlistLabel_2.Text = 'equipment list';
            app.Button_addEqData = uibutton(app.equipmentmanagementTab, 'push');
            app.Button_addEqData.ButtonPushedFcn = createCallbackFcn(app, @Button_addEqDataPushed, true);
            app.Button_addEqData.Text = 'add';
            app.Button_deleteEquipmentData = uibutton(app.equipmentmanagementTab, 'push');
            app.Button_deleteEquipmentData.ButtonPushedFcn = createCallbackFcn(app, @Button_deleteEquipmentDataPushed, true);
            app.Button_deleteEquipmentData.Text = 'delete';
            app.Button_updateEquipmentData = uibutton(app.equipmentmanagementTab, 'push');
            app.Button_updateEquipmentData.ButtonPushedFcn = createCallbackFcn(app, @Button_updateEquipmentDataPushed, true);
            app.Button_updateEquipmentData.Text = 'edit';
            app.Label_55 = uilabel(app.equipmentmanagementTab);
            app.Label_55.Text = 'EN:';
            app.ENTextArea = uitextarea(app.equipmentmanagementTab);
            app.Label_81 = uilabel(app.equipmentmanagementTab);
            app.Label_81.Text = 'EN:';
            app.EditField_equipName = uieditfield(app.equipmentmanagementTab, 'text');
            app.Label_82 = uilabel(app.equipmentmanagementTab);
            app.Label_82.Text = 'EC:';
            app.EditField_equipNo = uieditfield(app.equipmentmanagementTab, 'text');
            app.Label_84 = uilabel(app.equipmentmanagementTab);
            app.Label_84.Text = 'PCHT:';
            app.DatePicker_buyTime = uidatepicker(app.equipmentmanagementTab);
            app.DatePicker_buyTime.Value = datetime([2026 1 13]);
            app.Label_85 = uilabel(app.equipmentmanagementTab);
            app.Label_85.Text = 'memo:';
            app.TextArea_equipMemo = uitextarea(app.equipmentmanagementTab);
            app.Label_86 = uilabel(app.equipmentmanagementTab);
            app.Label_86.Text = 'ES:';
            app.EditField_equipCompany = uieditfield(app.equipmentmanagementTab, 'text');
            app.jobplanTab = uitab(app.TabGroup);
            app.jobplanTab.Title = 'job plan';
            app.UITable_jobPlan = uitable(app.jobplanTab);
            app.UITable_jobPlan.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_jobPlan.RowName = {'Column 1'; 'Column 2'};
            app.UITable_jobPlan.SelectionType = 'row';
            app.UITable_jobPlan.ColumnEditable = [true true true true true true true false false false false false false false false false false false false false false false false false false];
            app.queryButton_3 = uibutton(app.jobplanTab, 'push');
            app.queryButton_3.ButtonPushedFcn = createCallbackFcn(app, @queryButton_3Pushed, true);
            app.queryButton_3.Text = 'query';
            app.addButton = uibutton(app.jobplanTab, 'push');
            app.addButton.Text = 'add';
            app.deleteButton = uibutton(app.jobplanTab, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete';
            app.editButton = uibutton(app.jobplanTab, 'push');
            app.editButton.Text = 'edit';
            app.Label_58 = uilabel(app.jobplanTab);
            app.Label_58.Text = 'ONo:';
            app.TextArea_orderNoQry = uitextarea(app.jobplanTab);
            app.Label_60 = uilabel(app.jobplanTab);
            app.Label_60.Text = 'Sched Cyc:';
            app.TextArea_schPeriod = uitextarea(app.jobplanTab);
            app.Label_slatMsg = uilabel(app.jobplanTab);
            app.Label_slatMsg.Text = 'Message Display Area';
            app.jobschedulingTab = uitab(app.TabGroup);
            app.jobschedulingTab.Title = 'job scheduling';
            app.Label = uilabel(app.jobschedulingTab);
            app.Label.Text = 'OSV:';
            app.EditField_optimizedSof = uieditfield(app.jobschedulingTab, 'text');
            app.Label_4 = uilabel(app.jobschedulingTab);
            app.Label_4.Text = 'OOV:';
            app.EditField_bestObj = uieditfield(app.jobschedulingTab, 'text');
            app.SchedCycLabel = uilabel(app.jobschedulingTab);
            app.SchedCycLabel.Text = 'Sched Cyc:';
            app.TextArea_5 = uitextarea(app.jobschedulingTab);
            app.TabGroup2 = uitabgroup(app.jobschedulingTab);
            app.jobplanTab_2 = uitab(app.TabGroup2);
            app.jobplanTab_2.Title = 'job plan';
            app.UITable_9 = uitable(app.jobplanTab_2);
            app.UITable_9.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_9.RowName = {};
            app.UITable_9.SelectionType = 'row';
            app.UITable_9.ColumnEditable = [true true true true true true true false false false false false false false false false false false false false false false false false false];
            app.UITable_9.Multiselect = 'off';
            app.parameterSettingsTab = uitab(app.TabGroup2);
            app.parameterSettingsTab.Title = 'parameterSettings';
            app.Label_100 = uilabel(app.parameterSettingsTab);
            app.Label_100.Text = 'pops:';
            app.EditField_popSize = uieditfield(app.parameterSettingsTab, 'numeric');
            app.EditField_popSize.Value = 20;
            app.Label_101 = uilabel(app.parameterSettingsTab);
            app.Label_101.Text = 'evalt:';
            app.EditField_maxEva = uieditfield(app.parameterSettingsTab, 'numeric');
            app.EditField_maxEva.Value = 10000;
            app.Label_102 = uilabel(app.parameterSettingsTab);
            app.Label_102.Text = 'rt:';
            app.EditField_maxRun = uieditfield(app.parameterSettingsTab, 'numeric');
            app.EditField_maxRun.Value = 2;
            app.Button_qryJobplan = uibutton(app.jobschedulingTab, 'push');
            app.Button_qryJobplan.ButtonPushedFcn = createCallbackFcn(app, @Button_qryJobplanPushed, true);
            app.Button_qryJobplan.Text = 'query op plan';
            app.Button_scheduling = uibutton(app.jobschedulingTab, 'push');
            app.Button_scheduling.ButtonPushedFcn = createCallbackFcn(app, @Button_schedulingPushed, true);
            app.Button_scheduling.Text = 'start opt';
            app.Label_98 = uilabel(app.jobschedulingTab);
            app.Label_98.Text = 'algorithmName：';
            app.DropDown_algo = uidropdown(app.jobschedulingTab);
            app.DropDown_algo.Items = {'FJSP_PSO', 'FJSP_GO'};
            app.DropDown_algo.Value = 'FJSP_PSO';
            app.Label_99 = uilabel(app.jobschedulingTab);
            app.Label_99.Text = 'KPI：';
            app.DropDown_kpi = uidropdown(app.jobschedulingTab);
            app.DropDown_kpi.Items = {'doComputingMakespan'};
            app.DropDown_kpi.Value = 'doComputingMakespan';
            app.Label_103 = uilabel(app.jobschedulingTab);
            app.Label_103.Text = 'start time:';
            app.DatePicker_startTime = uidatepicker(app.jobschedulingTab);
            app.DatePicker_startTime.Value = datetime([2026 1 31]);
            app.Label_theme = uilabel(app.SIS_APS);
            app.Label_theme.Text = 'Swarm Intelli Scheduling';
            app.Label_message = uilabel(app.SIS_APS);
            app.Label_message.Text = 'Message Display Area';
            % Show the figure after all components are created
            app.SIS_APS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_SISAPS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SIS_APS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SIS_APS)
        end
    end
end